classdef appForLake < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ControlSwitchLabel             matlab.ui.control.Label
        ControlSwitch                  matlab.ui.control.Switch
        STBDEditFieldLabel             matlab.ui.control.Label
        STBDEditField                  matlab.ui.control.NumericEditField
        PORTEditFieldLabel             matlab.ui.control.Label
        PORTEditField                  matlab.ui.control.NumericEditField
        ELEVATOREditFieldLabel         matlab.ui.control.Label
        ELEVATOREditField              matlab.ui.control.NumericEditField
        RUDDEREditFieldLabel           matlab.ui.control.Label
        RUDDEREditField                matlab.ui.control.NumericEditField
        RunCounterEditFieldLabel       matlab.ui.control.Label
        RunCounterEditField            matlab.ui.control.NumericEditField
        RunCounterEditField_2Label     matlab.ui.control.Label
        RunCounterEditField_2          matlab.ui.control.NumericEditField
        RunCounterEditField_3Label     matlab.ui.control.Label
        RunCounterEditField_3          matlab.ui.control.NumericEditField
        BoatSpeedmsEditFieldLabel      matlab.ui.control.Label
        BoatSpeedmsEditField           matlab.ui.control.NumericEditField
        ManualMotorCMDVEditFieldLabel  matlab.ui.control.Label
        ManualMotorCMDVEditField       matlab.ui.control.NumericEditField
        V0rpm05Vmaxspeed025Vspoolin255VspooloutLabel  matlab.ui.control.Label
        MotorOverrideSwitchLabel       matlab.ui.control.Label
        MotorOverrideSwitch            matlab.ui.control.Switch
        ManualLabel                    matlab.ui.control.Label
        IntResetButton                 matlab.ui.control.Button
        KPEditFieldLabel               matlab.ui.control.Label
        KPEditField                    matlab.ui.control.NumericEditField
        KDEditFieldLabel               matlab.ui.control.Label
        KDEditField                    matlab.ui.control.NumericEditField
        KIEditFieldLabel               matlab.ui.control.Label
        KIEditField                    matlab.ui.control.NumericEditField
        IIRSwitchLabel                 matlab.ui.control.Label
        IIRSwitch                      matlab.ui.control.Switch
        MotorOverrideSwitch_3          matlab.ui.control.Switch
        ONYawdriventozeroLabel         matlab.ui.control.Label
        BatteryvoltageVEditFieldLabel  matlab.ui.control.Label
        BatteryvoltageVEditField       matlab.ui.control.NumericEditField
        AzimtuhcontrollerLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ControlSwitch
        function ControlSwitchValueChanged(app, event)
            control = app.ControlSwitch.Value;
            if strcmp(control,'Manual')
                value = 1;
            else
                value = -1;
            end
            set_param([bdroot,'/MANUAL CONTROL'],'Value',num2str(value))
        end

        % Value changed function: STBDEditField
        function STBDEditFieldValueChanged(app, event)
            value = app.STBDEditField.Value;
            set_param([bdroot,'/SAileron_manual'],'Value',num2str(value))
        end

        % Value changed function: PORTEditField
        function PORTEditFieldValueChanged(app, event)
            value = app.PORTEditField.Value;
            set_param([bdroot,'/PAileron_manual'],'Value',num2str(value))
        end

        % Value changed function: ELEVATOREditField
        function ELEVATOREditFieldValueChanged(app, event)
            value = app.ELEVATOREditField.Value; 
            set_param([bdroot,'/elevator_manual'],'Value',num2str(value))
        end

        % Value changed function: RUDDEREditField
        function RUDDEREditFieldValueChanged(app, event)
            value = app.RUDDEREditField.Value; 
            set_param([bdroot,'/rudder_manual'],'Value',num2str(value))
        end

        % Value changed function: RunCounterEditField_3
        function RunCounterEditField_3ValueChanged(app, event)
            value = app.RunCounterEditField_3.Value;
            set_param([bdroot,'/runCounter'],'Value',num2str(value))
        end

        % Value changed function: BoatSpeedmsEditField
        function BoatSpeedmsEditFieldValueChanged(app, event)
            value = app.BoatSpeedmsEditField.Value;
            set_param([bdroot,'/Sensor Processing/BoatSpeed'],'Value',num2str(value))
        end

        % Value changed function: ManualMotorCMDVEditField
        function ManualMotorCMDVEditFieldValueChanged(app, event)
            value = app.ManualMotorCMDVEditField.Value;
            set_param([bdroot,'/tension controller/MANUAL_CMD'],'Value',num2str(value))
        end

        % Value changed function: MotorOverrideSwitch
        function MotorOverrideSwitchValueChanged(app, event)
            overr = app.MotorOverrideSwitch.Value;
            
            if strcmp(overr,'ON')
                value = 1;
            else
                value = 0;
            end
            set_param([bdroot,'/tension controller/SPEED_MANUAL'],'Value',num2str(value))
        end

        % Button pushed function: IntResetButton
        function IntResetButtonPushed(app, event)
            set_param([bdroot,'/Sensor Processing/FilteredPID/Int_reset'],'Value','1')
            set_param([bdroot,'/Sensor Processing/FilteredPID/Int_reset1'],'Value','1')
            
            set_param([bdroot,'/Sensor Processing/FilteredPID/Int_reset'],'Value','0')
            set_param([bdroot,'/Sensor Processing/FilteredPID/Int_reset1'],'Value','0')
        end

        % Value changed function: KPEditField
        function KPEditFieldValueChanged(app, event)
            value = app.KPEditField.Value;
            set_param([bdroot,'/Sensor Processing/FilteredPID/KP'],'Gain',num2str(value))
        end

        % Value changed function: KDEditField
        function KDEditFieldValueChanged(app, event)
            value = app.KDEditField.Value;
            set_param([bdroot,'/Sensor Processing/FilteredPID/KD'],'Gain',num2str(value))
        end

        % Value changed function: KIEditField
        function KIEditFieldValueChanged(app, event)
            value = app.KIEditField.Value;
            set_param([bdroot,'/Sensor Processing/FilteredPID/KI'],'Gain',num2str(value))
        end

        % Value changed function: IIRSwitch
        function IIRSwitchValueChanged(app, event)
            iir = app.IIRSwitch.Value;

            
            if strcmp(iir,'ON')
                value = 1;
            else
                value = -1;
            end
            set_param([bdroot,'/Sensor Processing/IIRswitch'],'Value',num2str(value))
            
        end

        % Value changed function: MotorOverrideSwitch_3
        function MotorOverrideSwitch_3ValueChanged(app, event)
            rudd = app.MotorOverrideSwitch_3.Value;
            
            if strcmp(rudd,'ON')
                value = 1;
            else
                value = 0;
            end
            set_param([bdroot,'/Rudder Servo Mapping/RuddOnOff'],'Gain',num2str(value))
        end

        % Value changed function: BatteryvoltageVEditField
        function BatteryvoltageVEditFieldValueChanged(app, event)
            value = app.BatteryvoltageVEditField.Value;
            set_param([bdroot,'/BatteryVoltage'],'Value',num2str(value))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ControlSwitchLabel = uilabel(app.UIFigure);
            app.ControlSwitchLabel.Text = 'Control';
            app.ControlSwitch = uiswitch(app.UIFigure, 'slider');
            app.ControlSwitch.Items = {'Automatic', 'Manual'};
            app.ControlSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlSwitchValueChanged, true);
            app.ControlSwitch.Value = 'Automatic';
            app.STBDEditFieldLabel = uilabel(app.UIFigure);
            app.STBDEditFieldLabel.Text = 'STBD';
            app.STBDEditField = uieditfield(app.UIFigure, 'numeric');
            app.STBDEditField.ValueChangedFcn = createCallbackFcn(app, @STBDEditFieldValueChanged, true);
            app.PORTEditFieldLabel = uilabel(app.UIFigure);
            app.PORTEditFieldLabel.Text = 'PORT';
            app.PORTEditField = uieditfield(app.UIFigure, 'numeric');
            app.PORTEditField.ValueChangedFcn = createCallbackFcn(app, @PORTEditFieldValueChanged, true);
            app.ELEVATOREditFieldLabel = uilabel(app.UIFigure);
            app.ELEVATOREditFieldLabel.Text = 'ELEVATOR';
            app.ELEVATOREditField = uieditfield(app.UIFigure, 'numeric');
            app.ELEVATOREditField.ValueChangedFcn = createCallbackFcn(app, @ELEVATOREditFieldValueChanged, true);
            app.RUDDEREditFieldLabel = uilabel(app.UIFigure);
            app.RUDDEREditFieldLabel.Text = 'RUDDER';
            app.RUDDEREditField = uieditfield(app.UIFigure, 'numeric');
            app.RUDDEREditField.ValueChangedFcn = createCallbackFcn(app, @RUDDEREditFieldValueChanged, true);
            app.RunCounterEditFieldLabel = uilabel(app.UIFigure);
            app.RunCounterEditFieldLabel.Text = 'Run Counter ';
            app.RunCounterEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunCounterEditField.Limits = [0 120];
            app.RunCounterEditField_2Label = uilabel(app.UIFigure);
            app.RunCounterEditField_2Label.Text = 'Run Counter ';
            app.RunCounterEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RunCounterEditField_2.Limits = [0 120];
            app.RunCounterEditField_3Label = uilabel(app.UIFigure);
            app.RunCounterEditField_3Label.Text = 'Run Counter';
            app.RunCounterEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.RunCounterEditField_3.ValueChangedFcn = createCallbackFcn(app, @RunCounterEditField_3ValueChanged, true);
            app.BoatSpeedmsEditFieldLabel = uilabel(app.UIFigure);
            app.BoatSpeedmsEditFieldLabel.Text = 'Boat Speed (m/s)';
            app.BoatSpeedmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.BoatSpeedmsEditField.ValueChangedFcn = createCallbackFcn(app, @BoatSpeedmsEditFieldValueChanged, true);
            app.ManualMotorCMDVEditFieldLabel = uilabel(app.UIFigure);
            app.ManualMotorCMDVEditFieldLabel.Text = 'Manual Motor CMD (V)';
            app.ManualMotorCMDVEditField = uieditfield(app.UIFigure, 'numeric');
            app.ManualMotorCMDVEditField.ValueChangedFcn = createCallbackFcn(app, @ManualMotorCMDVEditFieldValueChanged, true);
            app.ManualMotorCMDVEditField.Value = 2.5;
            app.V0rpm05Vmaxspeed025Vspoolin255VspooloutLabel = uilabel(app.UIFigure);
            app.V0rpm05Vmaxspeed025Vspoolin255VspooloutLabel.Text = {'2.5V = 0 rpm; 0, 5V max speed'; ' 0-2.5V = spool in '; '2.5-5V = spool out'};
            app.MotorOverrideSwitchLabel = uilabel(app.UIFigure);
            app.MotorOverrideSwitchLabel.Text = 'Motor Override';
            app.MotorOverrideSwitch = uiswitch(app.UIFigure, 'slider');
            app.MotorOverrideSwitch.Items = {'OFF', 'ON'};
            app.MotorOverrideSwitch.ValueChangedFcn = createCallbackFcn(app, @MotorOverrideSwitchValueChanged, true);
            app.MotorOverrideSwitch.Value = 'OFF';
            app.ManualLabel = uilabel(app.UIFigure);
            app.ManualLabel.Text = '(Manual)';
            app.IntResetButton = uibutton(app.UIFigure, 'push');
            app.IntResetButton.ButtonPushedFcn = createCallbackFcn(app, @IntResetButtonPushed, true);
            app.IntResetButton.Text = 'Int Reset';
            app.KPEditFieldLabel = uilabel(app.UIFigure);
            app.KPEditFieldLabel.Text = 'KP';
            app.KPEditField = uieditfield(app.UIFigure, 'numeric');
            app.KPEditField.ValueChangedFcn = createCallbackFcn(app, @KPEditFieldValueChanged, true);
            app.KDEditFieldLabel = uilabel(app.UIFigure);
            app.KDEditFieldLabel.Text = 'KD';
            app.KDEditField = uieditfield(app.UIFigure, 'numeric');
            app.KDEditField.ValueChangedFcn = createCallbackFcn(app, @KDEditFieldValueChanged, true);
            app.KIEditFieldLabel = uilabel(app.UIFigure);
            app.KIEditFieldLabel.Text = 'KI';
            app.KIEditField = uieditfield(app.UIFigure, 'numeric');
            app.KIEditField.ValueChangedFcn = createCallbackFcn(app, @KIEditFieldValueChanged, true);
            app.IIRSwitchLabel = uilabel(app.UIFigure);
            app.IIRSwitchLabel.Text = 'IIR Switch';
            app.IIRSwitch = uiswitch(app.UIFigure, 'slider');
            app.IIRSwitch.Items = {'OFF', 'ON'};
            app.IIRSwitch.ValueChangedFcn = createCallbackFcn(app, @IIRSwitchValueChanged, true);
            app.IIRSwitch.Value = 'OFF';
            app.MotorOverrideSwitch_3 = uiswitch(app.UIFigure, 'slider');
            app.MotorOverrideSwitch_3.Items = {'OFF', 'ON'};
            app.MotorOverrideSwitch_3.ValueChangedFcn = createCallbackFcn(app, @MotorOverrideSwitch_3ValueChanged, true);
            app.MotorOverrideSwitch_3.Value = 'OFF';
            app.ONYawdriventozeroLabel = uilabel(app.UIFigure);
            app.ONYawdriventozeroLabel.Text = 'ON = Yaw driven to zero';
            app.BatteryvoltageVEditFieldLabel = uilabel(app.UIFigure);
            app.BatteryvoltageVEditFieldLabel.Text = 'Battery voltage (V)';
            app.BatteryvoltageVEditField = uieditfield(app.UIFigure, 'numeric');
            app.BatteryvoltageVEditField.ValueChangedFcn = createCallbackFcn(app, @BatteryvoltageVEditFieldValueChanged, true);
            app.BatteryvoltageVEditField.Value = 41.2;
            app.AzimtuhcontrollerLabel = uilabel(app.UIFigure);
            app.AzimtuhcontrollerLabel.Text = 'Azimtuh controller';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appForLake

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end