classdef appforPool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        WinchControlTab                 matlab.ui.container.Tab
        Winch1SpeedrpmSliderLabel       matlab.ui.control.Label
        Winch1SpeedrpmSlider            matlab.ui.control.Slider
        Winch1SpeedrpmEditFieldLabel    matlab.ui.control.Label
        Winch1SpeedrpmEditField         matlab.ui.control.NumericEditField
        EngageWinchesButton             matlab.ui.control.StateButton
        StartWinch12Button              matlab.ui.control.StateButton
        SpoolingDirectionSwitchLabel    matlab.ui.control.Label
        SpoolingDirectionSwitch         matlab.ui.control.Switch
        Winch1AmplituderpmEditFieldLabel  matlab.ui.control.Label
        Winch1AmplituderpmEditField     matlab.ui.control.NumericEditField
        Winch1PeriodsEditFieldLabel     matlab.ui.control.Label
        Winch1PeriodsEditField          matlab.ui.control.NumericEditField
        BrakingtimesEditFieldLabel      matlab.ui.control.Label
        BrakingtimesEditField           matlab.ui.control.NumericEditField
        SpoolingProfileButtonGroup      matlab.ui.container.ButtonGroup
        ConstantButton                  matlab.ui.control.ToggleButton
        SineButton                      matlab.ui.control.ToggleButton
        TriangleButton                  matlab.ui.control.ToggleButton
        Winch2SpeedrpmSliderLabel       matlab.ui.control.Label
        Winch2SpeedrpmSlider            matlab.ui.control.Slider
        SpoolingProfileButtonGroup_2    matlab.ui.container.ButtonGroup
        ConstantButton_2                matlab.ui.control.ToggleButton
        SineButton_2                    matlab.ui.control.ToggleButton
        TriangleButton_2                matlab.ui.control.ToggleButton
        Winch2SpeedrpmEditFieldLabel    matlab.ui.control.Label
        Winch2SpeedrpmEditField         matlab.ui.control.NumericEditField
        Winch2AmplituderpmEditFieldLabel  matlab.ui.control.Label
        Winch2AmplituderpmEditField     matlab.ui.control.NumericEditField
        Winch2PeriodsEditFieldLabel     matlab.ui.control.Label
        Winch2PeriodsEditField          matlab.ui.control.NumericEditField
        DualWinchModeSwitch             matlab.ui.control.Switch
        Winch1Label                     matlab.ui.control.Label
        Winch2Label                     matlab.ui.control.Label
        StartWinch2Button               matlab.ui.control.StateButton
        Lamp                            matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        SettingsforbothwinchesLabel     matlab.ui.control.Label
        TestStartStopLabel              matlab.ui.control.Label
        CalibrationLabel                matlab.ui.control.Label
        LASCalibrationButton            matlab.ui.control.StateButton
        RunCounterEditFieldLabel        matlab.ui.control.Label
        RunCounterEditField             matlab.ui.control.NumericEditField
        ControlONtimesEditField_2       matlab.ui.control.NumericEditField
        Winch1StopDistancemEditFieldLabel  matlab.ui.control.Label
        Winch1StopDistancemEditField    matlab.ui.control.NumericEditField
        IMUYawCalibrationButton         matlab.ui.control.StateButton
        ControlONtimesEditField_3       matlab.ui.control.NumericEditField
        KiteControlTab                  matlab.ui.container.Tab
        PitchControlGainsLabel          matlab.ui.control.Label
        RollControlGainsLabel           matlab.ui.control.Label
        YawControlGainsLabel            matlab.ui.control.Label
        KpLabel                         matlab.ui.control.Label
        KdLabel                         matlab.ui.control.Label
        KiLabel                         matlab.ui.control.Label
        TauLabel                        matlab.ui.control.Label
        RollGainsKp                     matlab.ui.control.NumericEditField
        RollGainsKd                     matlab.ui.control.NumericEditField
        RollGainsKi                     matlab.ui.control.NumericEditField
        RollGainsTau                    matlab.ui.control.NumericEditField
        PitchGainsKp                    matlab.ui.control.NumericEditField
        PitchGainsKd                    matlab.ui.control.NumericEditField
        PitchGainsKi                    matlab.ui.control.NumericEditField
        PitchGainsTau                   matlab.ui.control.NumericEditField
        YawGainsKp                      matlab.ui.control.NumericEditField
        YawGainsKd                      matlab.ui.control.NumericEditField
        YawGainsKi                      matlab.ui.control.NumericEditField
        YawGainsTau                     matlab.ui.control.NumericEditField
        ElevatorDeflectionClosedLoopdegEditFieldLabel  matlab.ui.control.Label
        ElevatorDeflectionClosedLoopdegEditField  matlab.ui.control.NumericEditField
        RollControlSwitch               matlab.ui.control.Switch
        PitchControlSwitch              matlab.ui.control.Switch
        YawControlSwitch                matlab.ui.control.Switch
        AileronDeflectiondegEditFieldLabel  matlab.ui.control.Label
        AileronDeflectiondegEditField   matlab.ui.control.NumericEditField
        RudderDeflectiondegEditFieldLabel  matlab.ui.control.Label
        RudderDeflectiondegEditField    matlab.ui.control.NumericEditField
        RollSetPointLabel               matlab.ui.control.Label
        SPEditFieldLabel                matlab.ui.control.Label
        RollConstantSP                  matlab.ui.control.EditField
        AmpEditFieldLabel               matlab.ui.control.Label
        RollSineAmp                     matlab.ui.control.EditField
        PeriodEditFieldLabel            matlab.ui.control.Label
        RollSinePeriod                  matlab.ui.control.EditField
        AmpEditField_2Label             matlab.ui.control.Label
        RollSquareAmp                   matlab.ui.control.EditField
        PeriodEditField_2Label          matlab.ui.control.Label
        RollSquarePeriod                matlab.ui.control.EditField
        PitchSetPointLabel_2            matlab.ui.control.Label
        SPEditField_2Label              matlab.ui.control.Label
        PitchConstantSP                 matlab.ui.control.EditField
        AmpEditField_3Label             matlab.ui.control.Label
        PitchSineAmp                    matlab.ui.control.EditField
        PeriodEditField_3Label          matlab.ui.control.Label
        PitchSinePeriod                 matlab.ui.control.EditField
        AmpEditField_4Label             matlab.ui.control.Label
        PitchSquareAmp                  matlab.ui.control.EditField
        PeriodEditField_4Label          matlab.ui.control.Label
        PitchSquarePeriod               matlab.ui.control.EditField
        YawSetPointLabel                matlab.ui.control.Label
        SPEditField_3Label              matlab.ui.control.Label
        YawConstantSP                   matlab.ui.control.EditField
        AmpEditField_5Label             matlab.ui.control.Label
        YawSineAmp                      matlab.ui.control.EditField
        PeriodEditField_5Label          matlab.ui.control.Label
        YawSinePeriod                   matlab.ui.control.EditField
        AmpEditField_6Label             matlab.ui.control.Label
        YawSquareAmp                    matlab.ui.control.EditField
        PeriodEditField_6Label          matlab.ui.control.Label
        YawSquarePeriod                 matlab.ui.control.EditField
        ControllerSwitchLabel           matlab.ui.control.Label
        ControllerSwitch                matlab.ui.control.ToggleSwitch
        IntegralControlResetButton      matlab.ui.control.Button
        ElevatorDeflectionOpenLoopdegLabel  matlab.ui.control.Label
        ElevatorDeflectionOpenLoopdegEditField  matlab.ui.control.NumericEditField
        RollSetPointButtons             matlab.ui.container.ButtonGroup
        RollSPConstantButton            matlab.ui.control.RadioButton
        RollSPSineButton                matlab.ui.control.RadioButton
        RollSPSquareButton              matlab.ui.control.RadioButton
        RollSPTSButton                  matlab.ui.control.RadioButton
        PitchSetPointButtons            matlab.ui.container.ButtonGroup
        PitchSPConstantButton           matlab.ui.control.RadioButton
        PitchSPSineButton               matlab.ui.control.RadioButton
        PitchSPSquareButton             matlab.ui.control.RadioButton
        PitchSPTSButton                 matlab.ui.control.RadioButton
        YawSetPointButtons              matlab.ui.container.ButtonGroup
        YawSPConstantButton             matlab.ui.control.RadioButton
        YawSPSineButton                 matlab.ui.control.RadioButton
        YawSPSquareButton               matlab.ui.control.RadioButton
        YawSPTSButton                   matlab.ui.control.RadioButton
        ControllerTriggerButtonGroup    matlab.ui.container.ButtonGroup
        ManualButton_2                  matlab.ui.control.RadioButton
        LASSpeedButton                  matlab.ui.control.RadioButton
        EncPositionButton               matlab.ui.control.RadioButton
        TimeButton                      matlab.ui.control.RadioButton
        ControlTriggTimeInput           matlab.ui.control.EditField
        ControlTriggLASSpeedInput       matlab.ui.control.EditField
        ControlTriggEncPosInput         matlab.ui.control.EditField
        PhaseLagLabel                   matlab.ui.control.Label
        RollSineLag                     matlab.ui.control.EditField
        PhaseLagLabel_2                 matlab.ui.control.Label
        RollSquareLag                   matlab.ui.control.EditField
        PhaseLagLabel_3                 matlab.ui.control.Label
        PitchSineLag                    matlab.ui.control.EditField
        PhaseLagLabel_4                 matlab.ui.control.Label
        PitchSquareLag                  matlab.ui.control.EditField
        PhaseLagLabel_5                 matlab.ui.control.Label
        YawSineLag                      matlab.ui.control.EditField
        PhaseLagLabel_6                 matlab.ui.control.Label
        YawSquareLag                    matlab.ui.control.EditField
        CloseToOpenTriggersLabel        matlab.ui.control.Label
        ZeroAmpTrigg                    matlab.ui.control.NumericEditField
        PreFlightRollControlSwitchLabel  matlab.ui.control.Label
        PreFlightRollControlSwitch      matlab.ui.control.Switch
        HierarchicalControllerTab       matlab.ui.container.Tab
        PerpendicularErrorControllerKpEditFieldLabel  matlab.ui.control.Label
        PerpendicularErrorControllerKpEditField  matlab.ui.control.NumericEditField
        VelocityAngleControllerKpEditFieldLabel  matlab.ui.control.Label
        VelocityAngleControllerKpEditField  matlab.ui.control.NumericEditField
        SideSlipAngleControllerKpEditFieldLabel  matlab.ui.control.Label
        SideSlipAngleControllerKpEditField  matlab.ui.control.NumericEditField
        TangentRollAngleControllerLabel  matlab.ui.control.Label
        TanRollKpLabel                  matlab.ui.control.Label
        TanRollKdLabel                  matlab.ui.control.Label
        TanRollTauLabel                 matlab.ui.control.Label
        TanRollKpValue                  matlab.ui.control.NumericEditField
        TanRollKdValue                  matlab.ui.control.NumericEditField
        TanRollTauValue                 matlab.ui.control.NumericEditField
        BasisParamatersLabel            matlab.ui.control.Label
        PathWidthdegEditFieldLabel      matlab.ui.control.Label
        PathWidthdegEditField           matlab.ui.control.NumericEditField
        PathHeightdegEditFieldLabel     matlab.ui.control.Label
        PathHeightdegEditField          matlab.ui.control.NumericEditField
        CenterElevationAngledegEditFieldLabel  matlab.ui.control.Label
        CenterElevationAngledegEditField  matlab.ui.control.NumericEditField
        CenterAzimuthAngledegEditFieldLabel  matlab.ui.control.Label
        CenterAzimuthAngledegEditField  matlab.ui.control.NumericEditField
        TetherLengthmEditFieldLabel     matlab.ui.control.Label
        TetherLengthmEditField          matlab.ui.control.NumericEditField
    end

    
    methods (Access = public)
        
        function updateGUI(app,varargin)
%             rto = get_param([bdroot,'/WinchDisplay'],'RuntimeObject');
%             val = rto.InputPort(1).Data;
%             app.dispTest.Value = num2str(val);
%             stateControl = app.ControllerSwitch.Value;
%             if strcmp(stateControl,'Off')
%                 % Query radio button selected
%                 buttonText = app.ControllerTriggerButtonGroup.SelectedObject.Text;            
%                 if strcmp(buttonText,'Time(s)')
%                     timeTrigg = app.ControlTriggTimeInput.Value;
%                 end
%                 if strcmp(buttonText,'LAS Speed')
%                     speedTrigg = app.ControlTriggLASSpeedInput.Value;
%                     
%                 end
%                 if strcmp(buttonText,'Enc Position')
%                     posTrigg = str2double(app.ControlTriggEncPosInput.Value); %string
%                     rto = get_param([bdroot,'/WinchDisplay'],'RuntimeObject');
%                     posCurr = rto.InputPort(1).Data;
%                     if posTrigg <= posCurr
%                         set(app.ControllerSwitch,'Value','On')
%                         ClosedLoopControlOn(app);
%                     end
%                 end
%             end
% 
%             
%             

        end
    end
    
    methods (Access = private)
        
        function ClosedLoopControlOn(app)
            RollSwitch = app.RollControlSwitch.Value;
            PitchSwitch = app.PitchControlSwitch.Value;
            YawSwitch = app.YawControlSwitch.Value;
            if strcmp(RollSwitch,'Open')
                value = 1;
                set_param([bdroot,'/RollOpenClose'],'Value',num2str(value))
            else
                value = -1;
                set_param([bdroot,'/RollOpenClose'],'Value',num2str(value))
            end
            if strcmp(PitchSwitch,'Open')
                value = 1;
                set_param([bdroot,'/PitchOpenClose'],'Value',num2str(value))
            else
                value = -1;
                set_param([bdroot,'/PitchOpenClose'],'Value',num2str(value))
            end
            if strcmp(YawSwitch,'Open')
                value = 1;
                set_param([bdroot,'/YawOpenClose'],'Value',num2str(value))
            else
                value = -1;
                set_param([bdroot,'/YawOpenClose'],'Value',num2str(value))
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Turn all amplitudes and speeds to zero
            set_param([bdroot,'/ConstantSP'],'Value','0')
            set_param([bdroot,'/ConstantSP2'],'Value','0')
            set_param([bdroot,'/TriGain'],'Gain','0')
            set_param([bdroot,'/TriGain2'],'Gain','0')
            set_param([bdroot,'/SineWave'],'Amplitude','0')
            set_param([bdroot,'/SineWave2'],'Amplitude','0')
            set_param([bdroot,'/CouplingOnOff'],'Gain','0')
            app.Winch1SpeedrpmEditField.Value = 0;
            app.Winch2SpeedrpmEditField.Value = 0;

            
            % Set periods and rampdown time to 1 second
            set_param([bdroot,'/StopTime'],'Value','0')
            set_param([bdroot,'/SineWave'],'Frequency','2*pi')
            set_param([bdroot,'/SineWave2'],'Frequency','2*pi')
            set_param([bdroot,'/TriangleWave'],'Freq','1')
            set_param([bdroot,'/TriangleWave2'],'Freq','1')
            
            % Set winches as disabled and powered off
            set_param([bdroot,'/WinchPower'],'Value','0')
            set_param([bdroot,'/Winch2Power'],'Value','0')
            set_param([bdroot,'/Winch1Enable'],'Value','0')
            set_param([bdroot,'/Winch2Enable'],'Value','0')
            
            % Set winches to spool-out mode
            set_param([bdroot,'/SpoolingDirection'],'Value','1')
            
            % Controller should be Open loop for Roll, Pitch, Yaw
            set_param([bdroot,'/RollOpenClose'],'Value',num2str(1));
            set_param([bdroot,'/PitchOpenClose'],'Value',num2str(1));
            set_param([bdroot,'/YawOpenClose'],'Value',num2str(1));
            
            
            % Set SPType to zeros
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ', '/RollSPType'], 'Value', num2str(1))
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ', '/PitchSPType'], 'Value', num2str(1))
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ', '/YawSPType'], 'Value', num2str(1))
            
            % Constant SP initialization
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ', '/ConstRollSP'], 'Value', num2str(0))
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ', '/ConstPitchSP'], 'Value', num2str(0))
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ', '/ConstYawSP'], 'Value', num2str(0))
            
            
            % Closed Loop Trigger constants
            set_param([bdroot, '/ControlTriggType'], 'Value', num2str(0))
            
            timeTrigg = app.ControlTriggTimeInput.Value;
            set_param([bdroot, '/TimeTrigg'], 'Value', timeTrigg)
            
            encPosTrigg = app.ControlTriggEncPosInput.Value;
            set_param([bdroot,'/EncPosTrigg'],'Value',num2str(encPosTrigg))
            
            lasSpeedTrigg = app.ControlTriggLASSpeedInput.Value;
            set_param([bdroot,'/LASspeedTrigg'],'Value',num2str(lasSpeedTrigg))
            
            set_param([bdroot,'/ManualTrigg'],'Value',num2str(0))
            
            set_param([bdroot,'/RollOpenClose'],'Value',num2str(0))
            set_param([bdroot,'/PitchOpenClose'],'Value',num2str(0))
            set_param([bdroot,'/YawOpenClose'],'Value',num2str(0))
            
            set_param([bdroot,'/Aileron_manual'],'Value',num2str(0))
            set_param([bdroot,'/ElevatorOpenLoop'],'Value',num2str(0))
            set_param([bdroot,'/ElevatorClosedLoop'],'Value',num2str(0))
            set_param([bdroot,'/Rudder_manual'],'Value',num2str(0))
            
%             loadComponent('pathFollowCtrlExp');
        end

        % Value changing function: Winch1SpeedrpmSlider
        function Winch1SpeedrpmSliderValueChanging(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/ConstantSP'],'Value',num2str(changingValue))
            app.Winch1SpeedrpmEditField.Value = changingValue;
        end

        % Value changed function: Winch1SpeedrpmEditField
        function Winch1SpeedrpmEditFieldValueChanged(app, event)
            value = app.Winch1SpeedrpmEditField.Value;
            set_param([bdroot,'/ConstantSP'],'Value',num2str(value))
            app.Winch1SpeedrpmSlider.Value = value;
        end

        % Value changed function: StartWinch12Button
        function StartWinch12ButtonValueChanged(app, event)
            value = app.StartWinch12Button.Value;
            set_param([bdroot,'/WinchPower'],'Value',num2str(value))
            app.Lamp_2.Color = [1-value,value,0];
            
            isCoupled = app.DualWinchModeSwitch.Value;
            if strcmp(isCoupled,'Coupled (W2 = W1 + W2)')
                app.Lamp_3.Color = [1-value,value,0];
            end
            
            % This is for soft e-stop implementation
%             rto = get_param(['towTest_dualWinch/EncPosEstimation ','/winchLinPos'],'RunTimeObject');
%             winchPosVal = rto.InputPort(1).Data;
%             set_param([bdroot,'/winchStartPos'],'Value',num2str(winchPosVal))
            
            % Closed loop control setup
%             rto2 = get_param([bdroot,'/currTime'],'RunTimeObject');
%             timeCurr = rto2.InputPort(1).Data;
%             set_param([bdroot,'/WinchStartTime'],'Value',num2str(timeCurr))
            
            % Switch the kite controller on after specified delay 
%             delayValue = app.ControlONtimesEditField.Value;
%             pause(delayValue)
%             set(app.ControllerSwitch,'Value','On')
%             ControllerSwitchValueChanged(app, event)
%             set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/RollSineTrigger'],'Value',num2str(1))
%             set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/YawSineTrigger'],'Value',num2str(1))
            
            % Reset Integral Control Windup
            set_param([bdroot,'/Kite Measurement and Control /Controller1/FilteredPIDController/Int_reset'],'Value','1')
            set_param([bdroot,'/Kite Measurement and Control /Controller1/FilteredPIDController1/Int_reset'],'Value','1')
            set_param([bdroot,'/Kite Measurement and Control /Controller1/FilteredPIDController2/Int_reset'],'Value','1')
            
            set_param([bdroot,'/Kite Measurement and Control /Controller1/FilteredPIDController/Int_reset'],'Value','0')
            set_param([bdroot,'/Kite Measurement and Control /Controller1/FilteredPIDController1/Int_reset'],'Value','0')
            set_param([bdroot,'/Kite Measurement and Control /Controller1/FilteredPIDController2/Int_reset'],'Value','0')
            
            % Deflect elevator (Open loop) when closed loop control starts
            % stops when turning winch off
%             if value == true
%                 set_param(['towTest_dualWinch','/elev_CL_ONOFF'],'Value',num2str(1))
%             else
%                 set_param(['towTest_dualWinch','/elev_CL_ONOFF'],'Value',num2str(0))
%             end
            

        end

        % Value changed function: EngageWinchesButton
        function EngageWinchesButtonValueChanged(app, event)
            value = app.EngageWinchesButton.Value;
            set_param([bdroot,'/Winch1Enable'],'Value',num2str(value))
            set_param([bdroot,'/Winch2Enable'],'Value',num2str(value))
            app.Lamp.Color = [1-value,value,0];
            
            % Initial Parameters
            
            % Winch Power
            set_param([bdroot,'/WinchPower'],'Value','0')
            app.StartWinch12Button.Value = 0;
            
            % Stopping Time
            set_param([bdroot,'/StopTime'],'Value','1')
            app.BrakingtimesEditField.Value = 1;         
            
        end

        % Value changed function: SpoolingDirectionSwitch
        function SpoolingDirectionSwitchValueChanged(app, event)
            direction = app.SpoolingDirectionSwitch.Value;
            if strcmp(direction,'In')
                value = 0;
            else
                value = 1;
            end
            set_param([bdroot,'/SpoolingDirection'],'Value',num2str(value))
        end

        % Selection changed function: SpoolingProfileButtonGroup
        function SpoolingProfileButtonGroupSelectionChanged(app, event)
            selectedButton = app.SpoolingProfileButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'Constant') % Zero sine and triangle
                set_param([bdroot,'/SineOnOff'],'Gain','0')
                set_param([bdroot,'/TriOnOff'],'Gain','0')
                
            elseif strcmp(selectedButton.Text,'Sine') % Zero triangle
                set_param([bdroot,'/SineOnOff'],'Gain','1')
                set_param([bdroot,'/TriOnOff'],'Gain','0')
                
            else                                      % Zero sine
                set_param([bdroot,'/SineOnOff'],'Gain','0')
                set_param([bdroot,'/TriOnOff'],'Gain','1')
                
            end            
        end

        % Value changed function: Winch1PeriodsEditField
        function Winch1PeriodsEditFieldValueChanged(app, event)
            value = app.Winch1PeriodsEditField.Value;
            set_param([bdroot,'/SineWave'],'Frequency',num2str(2*pi/value))
            set_param([bdroot,'/TriangleWave'],'Freq',num2str(1/value))
        end

        % Value changed function: Winch1AmplituderpmEditField
        function Winch1AmplituderpmEditFieldValueChanged(app, event)
            value = app.Winch1AmplituderpmEditField.Value;
            set_param([bdroot,'/SineWave'],'Amplitude',num2str(value))
            set_param([bdroot,'/TriGain'],'Gain',num2str(value))
        end

        % Value changed function: BrakingtimesEditField
        function BrakingtimesEditFieldValueChanged(app, event)
            value = app.BrakingtimesEditField.Value;
            set_param([bdroot,'/StopTime'],'Value',num2str(value))
            app.RampdowntimesEditField_2.Value = value;
        end

        % Callback function
        function WinchEnableButton_2ValueChanged(app, event)
            value = app.WinchEnableButton_2.Value;
            set_param([bdroot,'/Winch2Enable'],'Value',num2str(value))
            
            % Initial Parameters
            
            % Winch Power
            set_param([bdroot,'/Winch2Power'],'Value','0')
            app.StartWinch12Button.Value = 0;
            
            % Stopping Time %%%%%%%
            set_param([bdroot,'/StopTime'],'Value','1')
            app.BrakingtimesEditField.Value = 1;         
            
        end

        % Callback function
        function WinchONButton_2ValueChanged(app, event)
            value = app.WinchONButton_2.Value;
            set_param([bdroot,'/Winch2Power'],'Value',num2str(value))
        end

        % Callback function
        function RampdowntimesEditField_2ValueChanged(app, event)
            value = app.RampdowntimesEditField_2.Value;
            set_param([bdroot,'/StopTime'],'Value',num2str(value))
            app.BrakingtimesEditField.Value = value;
        end

        % Callback function
        function WinchSpeedrpmSliderValueChanging(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/ConstantSP2'],'Value',num2str(changingValue))
            app.Winch2SpeedrpmEditField.Value = changingValue;
        end

        % Callback function
        function WinchSpeedrpmEditField_2ValueChanged(app, event)
            value = app.Winch2SpeedrpmEditField.Value;
            set_param([bdroot,'/ConstantSP2'],'Value',num2str(value))
            app.WinchSpeedrpmSlider.Value = value;
        end

        % Callback function
        function WaveAmplituderpmEditFieldValueChanged(app, event)
            value = app.WaveAmplituderpmEditField.Value;
            set_param([bdroot,'/SineWave2'],'Amplitude',num2str(value))
            set_param([bdroot,'/TriGain2'],'Gain',num2str(value))
        end

        % Callback function
        function WavePeriodsEditField_2ValueChanged(app, event)
            value = app.Winch2PeriodsEditField.Value;
            set_param([bdroot,'/SineWave2'],'Frequency',num2str(2*pi/value))
            set_param([bdroot,'/TriangleWave2'],'Freq',num2str(1/value))
        end

        % Callback function
        function SpoolingProfileButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.SpoolingProfileButtonGroup_2.SelectedObject;
            
            if strcmp(selectedButton.Text,'Constant') % Zero sine and triangle
                set_param([bdroot,'/SineOnOff2'],'Gain','0')
                set_param([bdroot,'/TriOnOff2'],'Gain','0')
                
            elseif strcmp(selectedButton.Text,'Sine') % Zero triangle
                set_param([bdroot,'/SineOnOff2'],'Gain','1')
                set_param([bdroot,'/TriOnOff2'],'Gain','0')
                
            else                                      % Zero sine
                set_param([bdroot,'/SineOnOff2'],'Gain','0')
                set_param([bdroot,'/TriOnOff2'],'Gain','1')
                
            end
        end

        % Callback function
        function SpoolDirectionSwitch_2ValueChanged(app, event)
            value = app.SpoolDirectionSwitch_2.Value;
            
            if strcmp(value,'In')
                value = 0;
            else
                value = 1;
            end
            
            set_param([bdroot,'/Winch2Direction'],'Value',num2str(value))
        end

        % Value changed function: DualWinchModeSwitch
        function DualWinchModeSwitchValueChanged(app, event)
            value = app.DualWinchModeSwitch.Value;
            if strcmp(value,'Coupled (W2 = W1 + W2)')
                set_param([bdroot,'/SyncSwitch'],'Value','1')
                set_param([bdroot,'/CouplingOnOff'],'Gain','1')
            else
                set_param([bdroot,'/SyncSwitch'],'Value','0')
                set_param([bdroot,'/CouplingOnOff'],'Gain','0')
            end
        end

        % Value changed function: StartWinch2Button
        function StartWinch2ButtonValueChanged(app, event)
            value = app.StartWinch2Button.Value;
            set_param([bdroot,'/Winch2Power'],'Value',num2str(value))
            app.Lamp_3.Color = [1-value,value,0];
        end

        % Selection changed function: SpoolingProfileButtonGroup_2
        function SpoolingProfileButtonGroup_2SelectionChanged2(app, event)
            selectedButton = app.SpoolingProfileButtonGroup_2.SelectedObject;
            
            if strcmp(selectedButton.Text,'Constant') % Zero sine and triangle
                set_param([bdroot,'/SineOnOff2'],'Gain','0')
                set_param([bdroot,'/TriOnOff2'],'Gain','0')
                
            elseif strcmp(selectedButton.Text,'Sine') % Zero triangle
                set_param([bdroot,'/SineOnOff2'],'Gain','1')
                set_param([bdroot,'/TriOnOff2'],'Gain','0')
                
            else                                      % Zero sine
                set_param([bdroot,'/SineOnOff2'],'Gain','0')
                set_param([bdroot,'/TriOnOff2'],'Gain','1')
                
            end   
            
        end

        % Value changed function: Winch2SpeedrpmEditField
        function Winch2SpeedrpmEditFieldValueChanged(app, event)
            value = app.Winch2SpeedrpmEditField.Value;
            set_param([bdroot,'/ConstantSP2'],'Value',num2str(value))
            app.Winch2SpeedrpmSlider.Value = value;
        end

        % Value changed function: Winch2AmplituderpmEditField
        function Winch2AmplituderpmEditFieldValueChanged(app, event)
            value = app.Winch2AmplituderpmEditField.Value;
            set_param([bdroot,'/SineWave2'],'Amplitude',num2str(value))
            set_param([bdroot,'/TriGain2'],'Gain',num2str(value))
        end

        % Value changed function: Winch2PeriodsEditField
        function Winch2PeriodsEditFieldValueChanged(app, event)
            value = app.Winch2PeriodsEditField.Value;
            set_param([bdroot,'/SineWave2'],'Frequency',num2str(2*pi/value))
            set_param([bdroot,'/TriangleWave2'],'Freq',num2str(1/value))
        end

        % Value changed function: Winch2SpeedrpmSlider
        function Winch2SpeedrpmSliderValueChanged(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/ConstantSP2'],'Value',num2str(changingValue))
            app.Winch2SpeedrpmEditField.Value = changingValue;
        end

        % Value changed function: LASCalibrationButton
        function LASCalibrationButtonValueChanged(app, event)
            value = app.LASCalibrationButton.Value;
            LAScalTimeLen = app.ControlONtimesEditField_2.Value;
            pause(LAScalTimeLen);
            assignin('base', 'LAScalTimeLen', LAScalTimeLen)
            evalin('base', 'CalibrateLAS')
            set(app.LASCalibrationButton,'Value',false)
        end

        % Value changed function: RollGainsKp
        function RollGainsKpValueChanged(app, event)
            value = app.RollGainsKp.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Controller1/FilteredPIDController2','/RollKp'],'Gain',num2str(value))
        end

        % Value changed function: RollGainsKd
        function RollGainsKdValueChanged(app, event)
            value = app.RollGainsKd.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Controller1/FilteredPIDController2','/RollKd'],'Gain',num2str(value))
        end

        % Value changed function: RollGainsKi
        function RollGainsKiValueChanged(app, event)
            value = app.RollGainsKi.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Controller1/FilteredPIDController2','/RollKi'],'Gain',num2str(value))
        end

        % Value changed function: RollGainsTau
        function RollGainsTauValueChanged(app, event)
            value = app.RollGainsTau.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Controller1/FilteredPIDController2','/RollTau'],'Value',num2str(value))
        end

        % Value changed function: PitchGainsKp
        function PitchGainsKpValueChanged(app, event)
            value = app.PitchGainsKp.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Controller1/FilteredPIDController','/PitchKp'],'Gain',num2str(value))
        end

        % Value changed function: PitchGainsKd
        function PitchGainsKdValueChanged(app, event)
            value = app.PitchGainsKd.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Controller1/FilteredPIDController','/PitchKd'],'Gain',num2str(value))
        end

        % Value changed function: PitchGainsKi
        function PitchGainsKiValueChanged(app, event)
            value = app.PitchGainsKi.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Controller1/FilteredPIDController','/PitchKi'],'Gain',num2str(value))
        end

        % Value changed function: PitchGainsTau
        function PitchGainsTauValueChanged(app, event)
            value = app.PitchGainsTau.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Controller1/FilteredPIDController','/PitchTau'],'Value',num2str(value))
        end

        % Value changed function: YawGainsKp
        function YawGainsKpValueChanged(app, event)
            value = app.YawGainsKp.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Controller1/FilteredPIDController1','/YawKp'],'Gain',num2str(value))
        end

        % Value changed function: YawGainsKd
        function YawGainsKdValueChanged(app, event)
            value = app.YawGainsKd.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Controller1/FilteredPIDController1','/YawKd'],'Gain',num2str(value))
        end

        % Value changed function: YawGainsKi
        function YawGainsKiValueChanged(app, event)
            value = app.YawGainsKi.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Controller1/FilteredPIDController1','/YawKi'],'Gain',num2str(value))
        end

        % Value changed function: YawGainsTau
        function YawGainsTauValueChanged(app, event)
            value = app.YawGainsTau.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Controller1/FilteredPIDController1','/YawTau'],'Value',num2str(value))
        end

        % Value changed function: 
        % ElevatorDeflectionClosedLoopdegEditField
        function ElevatorDeflectionClosedLoopdegEditFieldValueChanged(app, event)
            value = app.ElevatorDeflectionClosedLoopdegEditField.Value;
            set_param([bdroot,'/ElevatorClosedLoop'],'Value',num2str(value))
            set_param(['towTest_dualWinch/pathFollowingControllerExp','/pathFollElevClosedLoop1'],'Value',num2str(value))
            set_param(['towTest_dualWinch/pathFollowingControllerExp','/pathFollElevClosedLoop2'],'Value',num2str(value))
        end

        % Value changed function: RollControlSwitch
        function RollControlSwitchValueChanged(app, event)
            direction = app.RollControlSwitch.Value;
            if strcmp(direction,'Open')
                value = 0;
            else
                value = 1;
            end
            set_param([bdroot,'/RollOpenClose'],'Value',num2str(value))
        end

        % Value changed function: PitchControlSwitch
        function PitchControlSwitchValueChanged(app, event)
            direction = app.PitchControlSwitch.Value;
            if strcmp(direction,'Open')
                value = 0;
            else
                value = 1;
            end
            set_param([bdroot,'/PitchOpenClose'],'Value',num2str(value))
        end

        % Value changed function: YawControlSwitch
        function YawControlSwitchValueChanged(app, event)
            direction = app.YawControlSwitch.Value;
            if strcmp(direction,'Open')
                value = 0;
            else
                value = 1;
            end
            set_param([bdroot,'/YawOpenClose'],'Value',num2str(value))
        end

        % Value changed function: AileronDeflectiondegEditField
        function AileronDeflectiondegEditFieldValueChanged(app, event)
            value = app.AileronDeflectiondegEditField.Value;
            set_param([bdroot,'/Aileron_manual'],'Value',num2str(value))
        end

        % Value changed function: RudderDeflectiondegEditField
        function RudderDeflectiondegEditFieldValueChanged(app, event)
            value = app.RudderDeflectiondegEditField.Value;
            set_param([bdroot,'/Rudder_manual'],'Value',num2str(value))
        end

        % Value changed function: RollConstantSP
        function RollConstantSPValueChanged(app, event)
            value = app.RollConstantSP.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/ConstRollSP'],'Value',num2str(value))
        end

        % Value changed function: PitchConstantSP
        function PitchConstantSPValueChanged(app, event)
            value = app.PitchConstantSP.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/ConstPitchSP'],'Value',num2str(value))
        end

        % Value changed function: YawConstantSP
        function YawConstantSPValueChanged(app, event)
            value = app.YawConstantSP.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/ConstYawSP'],'Value',num2str(value))
        end

        % Value changed function: RollSineAmp
        function RollSineAmpValueChanged(app, event)
            value = app.RollSineAmp.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/RollSine'],'Amplitude',num2str(value))
        end

        % Value changed function: RollSinePeriod
        function RollSinePeriodValueChanged(app, event)
            value = app.RollSinePeriod.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/RollSine'],'Frequency',num2str(2*pi/str2double(value)))
        end

        % Value changed function: RollSquareAmp
        function RollSquareAmpValueChanged(app, event)
            value = app.RollSquareAmp.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/RollSquare'],'Amplitude',num2str(value))
        end

        % Value changed function: RollSquarePeriod
        function RollSquarePeriodValueChanged(app, event)
            value = app.RollSquarePeriod.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/RollSquare'],'Frequency',num2str(2*pi/str2double(value)))
        end

        % Value changed function: PitchSineAmp
        function PitchSineAmpValueChanged(app, event)
            value = app.PitchSineAmp.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/PitchSine'],'Amplitude',num2str(value))
        end

        % Value changed function: PitchSinePeriod
        function PitchSinePeriodValueChanged(app, event)
            value = app.PitchSinePeriod.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/PitchSine'],'Frequency',num2str(2*pi/str2double(value)))
        end

        % Value changed function: PitchSquareAmp
        function PitchSquareAmpValueChanged(app, event)
            value = app.PitchSquareAmp.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/PitchSquare'],'Amplitude',num2str(value))
        end

        % Value changed function: PitchSquarePeriod
        function PitchSquarePeriodValueChanged(app, event)
            value = app.PitchSquarePeriod.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/PitchSquare'],'Frequency',num2str(2*pi/str2double(value)))
        end

        % Value changed function: YawSineAmp
        function YawSineAmpValueChanged(app, event)
            value = app.YawSineAmp.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/YawSine'],'Amplitude',num2str(value))
        end

        % Value changed function: YawSinePeriod
        function YawSinePeriodValueChanged(app, event)
            value = app.YawSinePeriod.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/YawSine'],'Frequency',num2str(2*pi/str2double(value)))
        end

        % Value changed function: YawSquareAmp
        function YawSquareAmpValueChanged(app, event)
            value = app.YawSquareAmp.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/YawSquare'],'Amplitude',num2str(value))
        end

        % Value changed function: YawSquarePeriod
        function YawSquarePeriodValueChanged(app, event)
            value = app.YawSquarePeriod.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/YawSquare'],'Frequency',num2str(2*pi/str2double(value)))
        end

        % Value changed function: ControllerSwitch
        function ControllerSwitchValueChanged(app, event)
            direction = app.ControllerSwitch.Value;
            if strcmp(direction,'On')
                set_param([bdroot,'/ManualTrigg'],'Value',num2str(1))
            else
                set_param([bdroot,'/ManualTrigg'],'Value',num2str(0))
            end
%             RollSwitch = app.RollControlSwitch.Value;
%             PitchSwitch = app.PitchControlSwitch.Value;
%             YawSwitch = app.YawControlSwitch.Value;  
%             if strcmp(direction,'On')
%                 if strcmp(RollSwitch,'Open')
%                     value = 1;
%                     set_param([bdroot,'/RollOpenClose'],'Value',num2str(value))
%                 else
%                     value = -1;
%                     set_param([bdroot,'/RollOpenClose'],'Value',num2str(value))
% %                     if buttonText
%                 end
%                 if strcmp(PitchSwitch,'Open')
%                     value = 1;
%                     set_param([bdroot,'/PitchOpenClose'],'Value',num2str(value))
%                 else
%                     value = -1;
%                     set_param([bdroot,'/PitchOpenClose'],'Value',num2str(value))
%                 end
%                 if strcmp(YawSwitch,'Open')
%                     value = 1;
%                     set_param([bdroot,'/YawOpenClose'],'Value',num2str(value))
%                 else
%                     value = -1;
%                     set_param([bdroot,'/YawOpenClose'],'Value',num2str(value))
%                 end 
%             else
%                 app.RollControlSwitch.Value = 'Open';
%                 app.PitchControlSwitch.Value = 'Open';
%                 app.YawControlSwitch.Value = 'Open';
%                 value = 1;
%                 set_param([bdroot,'/RollOpenClose'],'Value',num2str(value))
%                 set_param([bdroot,'/PitchOpenClose'],'Value',num2str(value))
%                 set_param([bdroot,'/YawOpenClose'],'Value',num2str(value))
%                 set_param(['towTest_dualWinch','/elev_CL_ONOFF'],'Value',num2str(0))
%             end
        end

        % Value changed function: RunCounterEditField
        function RunCounterEditFieldValueChanged(app, event)
            value = app.RunCounterEditField.Value;
            set_param(['towTest_dualWinch','/runCounter'],'Value',num2str(value))
        end

        % Value changed function: ControlONtimesEditField_2
        function ControlONtimesEditField_2ValueChanged(app, event)
            
        end

        % Button pushed function: IntegralControlResetButton
        function IntegralControlResetButtonPushed(app, event)
                        
            set_param([bdroot,'/Kite Measurement and Control /Controller1/FilteredPIDController/Int_reset'],'Value','1')
            set_param([bdroot,'/Kite Measurement and Control /Controller1/FilteredPIDController1/Int_reset'],'Value','1')
            set_param([bdroot,'/Kite Measurement and Control /Controller1/FilteredPIDController2/Int_reset'],'Value','1')
            
            set_param([bdroot,'/Kite Measurement and Control /Controller1/FilteredPIDController/Int_reset'],'Value','0')
            set_param([bdroot,'/Kite Measurement and Control /Controller1/FilteredPIDController1/Int_reset'],'Value','0')
            set_param([bdroot,'/Kite Measurement and Control /Controller1/FilteredPIDController2/Int_reset'],'Value','0') 
            
                       
            yawTrigVal = get_param(['towTest_dualWinch/Kite Measurement and Control /Kite Measurements ','/yawEstimateTrigger'],'Value');
            yawTrigVal = str2double(yawTrigVal);
            if yawTrigVal == 1
                set_param(['towTest_dualWinch/Kite Measurement and Control /Kite Measurements ','/yawEstimateTrigger'],'Value',num2str(0))
            else
                set_param(['towTest_dualWinch/Kite Measurement and Control /Kite Measurements ','/yawEstimateTrigger'],'Value',num2str(1))
            end
            
            towPosTrigVal = get_param(['towTest_dualWinch/LAS_Calculation','/towPosResetTrigger'],'Value');
            towPosTrigVal = str2double(towPosTrigVal);
            if towPosTrigVal == 1
                set_param(['towTest_dualWinch/LAS_Calculation','/towPosResetTrigger'],'Value',num2str(0))
            else
                set_param(['towTest_dualWinch/LAS_Calculation','/towPosResetTrigger'],'Value',num2str(1))
            end
                         
        end

        % Value changed function: 
        % ElevatorDeflectionOpenLoopdegEditField
        function ElevatorDeflectionOpenLoopdegEditFieldValueChanged(app, event)
            elevForCL = app.ElevatorDeflectionOpenLoopdegEditField.Value;
            set_param([bdroot,'/ElevatorOpenLoop'],'Value',num2str(elevForCL))
        end

        % Selection changed function: RollSetPointButtons
        function RollSetPointButtonsSelectionChanged(app, event)
            buttonText = app.RollSetPointButtons.SelectedObject.Text;
            if strcmp(buttonText,'Constant')
                set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/RollSPType'],'value',num2str(1))
            elseif strcmp(buttonText,'Sine')
                set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/RollSPType'],'value',num2str(2))
            elseif strcmp(buttonText,'Square')
                set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/RollSPType'],'value',num2str(3))
            end
            
%             set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/ConstRollTrigger'],'Gain',num2str(1))
%             set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/RollSineTrigger'],'Value',num2str(0))
%             set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/RollSquareTrigger'],'Value',num2str(0))
%             set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ', '/RollTSTrigger'], 'Gain', num2str(0))
%             if strcmp(buttonText,'Constant')
%                 set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/ConstRollTrigger'],'Gain',num2str(1))
%                 set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/RollSPType'],'value',num2str(1))
%             elseif strcmp(buttonText,'Sine')
%                 set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/RollSineTrigger'],'Value',num2str(1))
%                 set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/RollSPType'],'value',num2str(2))
%             elseif strcmp(buttonText,'Square')
%                 set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/RollSquareTrigger'],'Value',num2str(1))
%                 set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/RollSPType'],'value',num2str(3))
%             elseif strcmp(buttonText,'TimeSeries')
%                 set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/RollTSTrigger'],'Gain',num2str(1))
%             end
            
        end

        % Selection changed function: PitchSetPointButtons
        function PitchSetPointButtonsSelectionChanged(app, event)
            buttonText = app.PitchSetPointButtons.SelectedObject.Text;
            if strcmp(buttonText,'Constant')
                set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/PitchSPType'],'value',num2str(1))
            elseif strcmp(buttonText,'Sine')
                set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/PitchSPType'],'value',num2str(2))
            elseif strcmp(buttonText,'Square')
                set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/PitchSPType'],'value',num2str(3))
            end
            
%             set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/ConstPitchTrigger'],'Gain',num2str(1))
%             set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/PitchSineTrigger'],'Value',num2str(0))
%             set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/PitchSquareTrigger'],'Value',num2str(0))
%             set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ', '/PitchTSTrigger'], 'Gain', num2str(0))
%             if strcmp(buttonText,'Constant')
%                 set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/ConstPitchTrigger'],'Gain',num2str(1))
%             elseif strcmp(buttonText,'Sine')
%                 set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/PitchSineTrigger'],'Value',num2str(1))
%             elseif strcmp(buttonText,'Square')
%                 set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/PitchSquareTrigger'],'Value',num2str(1))
%             elseif strcmp(buttonText,'TimeSeries')
%                 set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/PitchTSTrigger'],'Gain',num2str(1))
%             end
            
        end

        % Selection changed function: YawSetPointButtons
        function YawSetPointButtonsSelectionChanged(app, event)
            buttonText = app.YawSetPointButtons.SelectedObject.Text;
            if strcmp(buttonText,'Constant')
                set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/YawSPType'],'value',num2str(1))
            elseif strcmp(buttonText,'Sine')
                set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/YawSPType'],'value',num2str(2))
            elseif strcmp(buttonText,'Square')
                set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/YawSPType'],'value',num2str(3))
            end
            
%             set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/ConstYawTrigger'],'Gain',num2str(1))
%             set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/YawSineTrigger'],'Value',num2str(0))
%             set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/YawSquareTrigger'],'Value',num2str(0))
%             set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ', '/YawTSTrigger'], 'Gain', num2str(0))
%             if strcmp(buttonText,'Constant')
%                 set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/ConstYawTrigger'],'Gain',num2str(1))
%             elseif strcmp(buttonText,'Sine')
%                 set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/YawSineTrigger'],'Value',num2str(1))
%             elseif strcmp(buttonText,'Square')
%                 set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/YawSquareTrigger'],'Value',num2str(1))
%             elseif strcmp(buttonText,'TimeSeries')
%                 set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/YawTSTrigger'],'Gain',num2str(1))
%             end
            
        end

        % Callback function
        function StopWinchEncoderPositionEditFieldValueChanged(app, event)
            value = app.StopWinchEncoderPositionEditField.Value;
            set_param([bdroot,'/WinchPositionThreshold'],'Threshold',num2str(value))
        end

        % Selection changed function: ControllerTriggerButtonGroup
        function ControllerTriggerButtonGroupSelectionChanged(app, event)
            selectedButton = app.ControllerTriggerButtonGroup.SelectedObject;
            buttText = selectedButton.Text;
            if strcmp(buttText,'Time')
                set_param([bdroot,'/ControlTriggType'],'Value',num2str(1));
            elseif strcmp(buttText,'LAS Speed')
                set_param([bdroot,'/ControlTriggType'],'Value',num2str(2));
            elseif strcmp(buttText,'Enc Position')
                set_param([bdroot,'/ControlTriggType'],'Value',num2str(3));
            elseif strcmp(buttText,'Manual')
                set_param([bdroot,'/ControlTriggType'],'Value',num2str(0));
            end
            
        end

        % Value changed function: ControlTriggTimeInput
        function ControlTriggTimeInputValueChanged(app, event)
            value = app.ControlTriggTimeInput.Value;
            set_param([bdroot,'/TimeTrigg'],'Value',num2str(value))
        end

        % Value changed function: YawSineLag
        function YawSineLagValueChanged(app, event)
            value = app.YawSineLag.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/YawSine'],'Phase',num2str(value))
        end

        % Value changed function: RollSineLag
        function RollSineLagValueChanged(app, event)
            value = app.RollSineLag.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/RollSine'],'Phase',num2str(value))
        end

        % Value changed function: RollSquareLag
        function RollSquareLagValueChanged(app, event)
            value = app.RollSquareLag.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/RollSquare'],'Phase',num2str(value))
        end

        % Value changed function: PitchSineLag
        function PitchSineLagValueChanged(app, event)
            value = app.PitchSineLag.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/PitchSine'],'Phase',num2str(value))
        end

        % Value changed function: PitchSquareLag
        function PitchSquareLagValueChanged(app, event)
            value = app.PitchSquareLag.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/PitchSquare'],'Phase',num2str(value))
        end

        % Value changed function: YawSquareLag
        function YawSquareLagValueChanged(app, event)
            value = app.YawSquareLag.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/YawSquare'],'Phase',num2str(value))
        end

        % Value changed function: ControlTriggLASSpeedInput
        function ControlTriggLASSpeedInputValueChanged(app, event)
            value = app.ControlTriggLASSpeedInput.Value;
            set_param([bdroot,'/LASspeedTrigg'],'Value',num2str(value))
        end

        % Value changed function: ControlTriggEncPosInput
        function ControlTriggEncPosInputValueChanged(app, event)
            value = app.ControlTriggEncPosInput.Value;
            set_param([bdroot,'/EncPosTrigg'],'Value',num2str(value))
        end

        % Value changed function: Winch1StopDistancemEditField
        function Winch1StopDistancemEditFieldValueChanged(app, event)
            value = app.Winch1StopDistancemEditField.Value;
            set_param([bdroot,'/winchStopLen'],'Value',num2str(value))
        end

        % Value changed function: IMUYawCalibrationButton
        function IMUYawCalibrationButtonValueChanged(app, event)
            value = app.IMUYawCalibrationButton.Value;
            IMUcalTimeLen = app.ControlONtimesEditField_3.Value;
            pause(IMUcalTimeLen);
            assignin('base', 'IMUcalTimeLen', IMUcalTimeLen)
            evalin('base', 'CalibrateIMUyaw')
            set(app.IMUYawCalibrationButton,'Value',false)
        end

        % Value changed function: ZeroAmpTrigg
        function ZeroAmpTriggValueChanged(app, event)
            value = app.ZeroAmpTrigg.Value;
            set_param(['towTest_dualWinch/Kite Measurement and Control /Euler setpoints ','/ZeroAmpTrigger'],'Value',num2str(value))
        end

        % Value changed function: PreFlightRollControlSwitch
        function PreFlightRollControlSwitchValueChanged(app, event)
            direction = app.PreFlightRollControlSwitch.Value;
            if strcmp(direction,'Open')
                set_param([bdroot,'/PreflightRollControl'],'Value',num2str(1))
            else
                set_param([bdroot,'/PreflightRollControl'],'Value',num2str(-1))
            end
        end

        % Value changed function: TanRollKpValue
        function TanRollKpValueValueChanged(app, event)
            value = app.TanRollKpValue.Value;
            tanRollKpstr = append("fltCtrl.rollMoment.kp.setValue(",num2str(value),",'(N*m)/(rad)')");
            evalin('base',tanRollKpstr);
        end

        % Value changed function: TanRollKdValue
        function TanRollKdValueValueChanged(app, event)
            value = app.TanRollKdValue.Value;
            tanRollKdstr = append("fltCtrl.rollMoment.kd.setValue(",num2str(value),",'(N*m)/(rad/s)')");
            evalin('base',tanRollKdstr);
        end

        % Value changed function: TanRollTauValue
        function TanRollTauValueValueChanged(app, event)
            value = app.TanRollTauValue.Value;
            tanRollTaustr = append("fltCtrl.rollMoment.tau.setValue(",num2str(value),",'s')");
            evalin('base',tanRollTaustr);
        end

        % Value changed function: 
        % PerpendicularErrorControllerKpEditField
        function PerpendicularErrorControllerKpEditFieldValueChanged(app, event)
            value = app.PerpendicularErrorControllerKpEditField.Value;
            perpErrorKpstr = append("fltCtrl.perpErrorVal.setValue(",num2str(value),",'rad')");
            evalin('base',perpErrorKpstr);
        end

        % Value changed function: VelocityAngleControllerKpEditField
        function VelocityAngleControllerKpEditFieldValueChanged(app, event)
            value = app.VelocityAngleControllerKpEditField.Value;
            velAngCtrlKpstr = append("fltCtrl.tanRoll.kp.setValue(",num2str(value),",'(rad)/(rad)')");
            evalin('base',velAngCtrlKpstr);
        end

        % Value changed function: SideSlipAngleControllerKpEditField
        function SideSlipAngleControllerKpEditFieldValueChanged(app, event)
            value = app.SideSlipAngleControllerKpEditField.Value;
            sideSlipAngleCtrlKpstr = append("fltCtrl.yawMoment.kp.setValue(",num2str(value),",'(N*m)/(rad)')");
            evalin('base',sideSlipAngleCtrlKpstr);
        end

        % Value changed function: PathWidthdegEditField
        function PathWidthdegEditFieldValueChanged(app, event)
            val1 = app.PathWidthdegEditField.Value;
            val2 = app.PathHeightdegEditField.Value;
            val3 = app.CenterElevationAngledegEditField.Value;
            val4 = app.CenterAzimuthAngledegEditField.Value;
            val5 = app.TetherLengthmEditField.Value;
            valstr = append("[",num2str(val1)," ",num2str(val2)," ",num2str(val3)," ",num2str(val4)," ",num2str(val5),"]");
            setValstr = append("hiLvlCtrl.basisParams.setValue(",valstr,",'[rad rad rad rad m]')");
            evalin('base',setValstr);
        end

        % Value changed function: PathHeightdegEditField
        function PathHeightdegEditFieldValueChanged(app, event)
            PathWidthdegEditFieldValueChanged(app, event)
        end

        % Value changed function: CenterElevationAngledegEditField
        function CenterElevationAngledegEditFieldValueChanged(app, event)
            PathWidthdegEditFieldValueChanged(app, event)
        end

        % Value changed function: CenterAzimuthAngledegEditField
        function CenterAzimuthAngledegEditFieldValueChanged(app, event)
            PathWidthdegEditFieldValueChanged(app, event)
        end

        % Value changed function: TetherLengthmEditField
        function TetherLengthmEditFieldValueChanged(app, event)
            PathWidthdegEditFieldValueChanged(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WinchControlTab = uitab(app.TabGroup);
            app.WinchControlTab.Tooltip = {''};
            app.WinchControlTab.Title = 'Winch Control';
            app.Winch1SpeedrpmSliderLabel = uilabel(app.WinchControlTab);
            app.Winch1SpeedrpmSliderLabel.Text = 'Winch 1 Speed (rpm)';
            app.Winch1SpeedrpmSlider = uislider(app.WinchControlTab);
            app.Winch1SpeedrpmSlider.Limits = [0 120];
            app.Winch1SpeedrpmSlider.MajorTicks = [0 20 40 60 80 100 120];
            app.Winch1SpeedrpmSlider.ValueChangingFcn = createCallbackFcn(app, @Winch1SpeedrpmSliderValueChanging, true);
            app.Winch1SpeedrpmEditFieldLabel = uilabel(app.WinchControlTab);
            app.Winch1SpeedrpmEditFieldLabel.Text = 'Winch 1 Speed (rpm)';
            app.Winch1SpeedrpmEditField = uieditfield(app.WinchControlTab, 'numeric');
            app.Winch1SpeedrpmEditField.Limits = [0 120];
            app.Winch1SpeedrpmEditField.ValueChangedFcn = createCallbackFcn(app, @Winch1SpeedrpmEditFieldValueChanged, true);
            app.EngageWinchesButton = uibutton(app.WinchControlTab, 'state');
            app.EngageWinchesButton.ValueChangedFcn = createCallbackFcn(app, @EngageWinchesButtonValueChanged, true);
            app.EngageWinchesButton.Text = 'Engage Winches';
            app.StartWinch12Button = uibutton(app.WinchControlTab, 'state');
            app.StartWinch12Button.ValueChangedFcn = createCallbackFcn(app, @StartWinch12ButtonValueChanged, true);
            app.StartWinch12Button.Text = 'Start Winch 1 (+2)';
            app.SpoolingDirectionSwitchLabel = uilabel(app.WinchControlTab);
            app.SpoolingDirectionSwitchLabel.Text = 'Spooling Direction';
            app.SpoolingDirectionSwitch = uiswitch(app.WinchControlTab, 'slider');
            app.SpoolingDirectionSwitch.Items = {'In', 'Out'};
            app.SpoolingDirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @SpoolingDirectionSwitchValueChanged, true);
            app.SpoolingDirectionSwitch.Value = 'Out';
            app.Winch1AmplituderpmEditFieldLabel = uilabel(app.WinchControlTab);
            app.Winch1AmplituderpmEditFieldLabel.Text = 'Winch 1 Amplitude (rpm)';
            app.Winch1AmplituderpmEditField = uieditfield(app.WinchControlTab, 'numeric');
            app.Winch1AmplituderpmEditField.ValueChangedFcn = createCallbackFcn(app, @Winch1AmplituderpmEditFieldValueChanged, true);
            app.Winch1PeriodsEditFieldLabel = uilabel(app.WinchControlTab);
            app.Winch1PeriodsEditFieldLabel.Text = 'Winch 1 Period (s)';
            app.Winch1PeriodsEditField = uieditfield(app.WinchControlTab, 'numeric');
            app.Winch1PeriodsEditField.Limits = [0.1 Inf];
            app.Winch1PeriodsEditField.ValueChangedFcn = createCallbackFcn(app, @Winch1PeriodsEditFieldValueChanged, true);
            app.Winch1PeriodsEditField.Value = 1;
            app.BrakingtimesEditFieldLabel = uilabel(app.WinchControlTab);
            app.BrakingtimesEditFieldLabel.Text = 'Braking time (s)';
            app.BrakingtimesEditField = uieditfield(app.WinchControlTab, 'numeric');
            app.BrakingtimesEditField.Limits = [0.02 Inf];
            app.BrakingtimesEditField.ValueChangedFcn = createCallbackFcn(app, @BrakingtimesEditFieldValueChanged, true);
            app.BrakingtimesEditField.Value = 1;
            app.SpoolingProfileButtonGroup = uibuttongroup(app.WinchControlTab);
            app.SpoolingProfileButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpoolingProfileButtonGroupSelectionChanged, true);
            app.SpoolingProfileButtonGroup.Title = 'Spooling Profile';
            app.ConstantButton = uitogglebutton(app.SpoolingProfileButtonGroup);
            app.ConstantButton.Text = 'Constant';
            app.ConstantButton.Value = true;
            app.SineButton = uitogglebutton(app.SpoolingProfileButtonGroup);
            app.SineButton.Text = 'Sine';
            app.TriangleButton = uitogglebutton(app.SpoolingProfileButtonGroup);
            app.TriangleButton.Text = 'Triangle';
            app.Winch2SpeedrpmSliderLabel = uilabel(app.WinchControlTab);
            app.Winch2SpeedrpmSliderLabel.Text = 'Winch 2 Speed (rpm)';
            app.Winch2SpeedrpmSlider = uislider(app.WinchControlTab);
            app.Winch2SpeedrpmSlider.Limits = [0 120];
            app.Winch2SpeedrpmSlider.MajorTicks = [0 20 40 60 80 100 120];
            app.Winch2SpeedrpmSlider.ValueChangedFcn = createCallbackFcn(app, @Winch2SpeedrpmSliderValueChanged, true);
            app.SpoolingProfileButtonGroup_2 = uibuttongroup(app.WinchControlTab);
            app.SpoolingProfileButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @SpoolingProfileButtonGroup_2SelectionChanged2, true);
            app.SpoolingProfileButtonGroup_2.Title = 'Spooling Profile';
            app.ConstantButton_2 = uitogglebutton(app.SpoolingProfileButtonGroup_2);
            app.ConstantButton_2.Text = 'Constant';
            app.ConstantButton_2.Value = true;
            app.SineButton_2 = uitogglebutton(app.SpoolingProfileButtonGroup_2);
            app.SineButton_2.Text = 'Sine';
            app.TriangleButton_2 = uitogglebutton(app.SpoolingProfileButtonGroup_2);
            app.TriangleButton_2.Text = 'Triangle';
            app.Winch2SpeedrpmEditFieldLabel = uilabel(app.WinchControlTab);
            app.Winch2SpeedrpmEditFieldLabel.Text = 'Winch 2 Speed (rpm)';
            app.Winch2SpeedrpmEditField = uieditfield(app.WinchControlTab, 'numeric');
            app.Winch2SpeedrpmEditField.Limits = [0 120];
            app.Winch2SpeedrpmEditField.ValueChangedFcn = createCallbackFcn(app, @Winch2SpeedrpmEditFieldValueChanged, true);
            app.Winch2AmplituderpmEditFieldLabel = uilabel(app.WinchControlTab);
            app.Winch2AmplituderpmEditFieldLabel.Text = 'Winch 2 Amplitude (rpm)';
            app.Winch2AmplituderpmEditField = uieditfield(app.WinchControlTab, 'numeric');
            app.Winch2AmplituderpmEditField.ValueChangedFcn = createCallbackFcn(app, @Winch2AmplituderpmEditFieldValueChanged, true);
            app.Winch2PeriodsEditFieldLabel = uilabel(app.WinchControlTab);
            app.Winch2PeriodsEditFieldLabel.Text = 'Winch 2 Period (s)';
            app.Winch2PeriodsEditField = uieditfield(app.WinchControlTab, 'numeric');
            app.Winch2PeriodsEditField.Limits = [0.1 Inf];
            app.Winch2PeriodsEditField.ValueChangedFcn = createCallbackFcn(app, @Winch2PeriodsEditFieldValueChanged, true);
            app.Winch2PeriodsEditField.Value = 1;
            app.DualWinchModeSwitch = uiswitch(app.WinchControlTab, 'slider');
            app.DualWinchModeSwitch.Items = {'Coupled (W2 = W1 + W2)', 'Uncoupled'};
            app.DualWinchModeSwitch.ValueChangedFcn = createCallbackFcn(app, @DualWinchModeSwitchValueChanged, true);
            app.DualWinchModeSwitch.Value = 'Uncoupled';
            app.Winch1Label = uilabel(app.WinchControlTab);
            app.Winch1Label.Text = 'Winch 1';
            app.Winch2Label = uilabel(app.WinchControlTab);
            app.Winch2Label.Text = 'Winch 2';
            app.StartWinch2Button = uibutton(app.WinchControlTab, 'state');
            app.StartWinch2Button.ValueChangedFcn = createCallbackFcn(app, @StartWinch2ButtonValueChanged, true);
            app.StartWinch2Button.Text = 'Start Winch 2';
            app.Lamp = uilamp(app.WinchControlTab);
            app.Lamp_2 = uilamp(app.WinchControlTab);
            app.Lamp_3 = uilamp(app.WinchControlTab);
            app.SettingsforbothwinchesLabel = uilabel(app.WinchControlTab);
            app.SettingsforbothwinchesLabel.Text = 'Settings (for both winches)';
            app.TestStartStopLabel = uilabel(app.WinchControlTab);
            app.TestStartStopLabel.Text = 'Test Start/Stop';
            app.CalibrationLabel = uilabel(app.WinchControlTab);
            app.CalibrationLabel.Text = 'Calibration';
            app.LASCalibrationButton = uibutton(app.WinchControlTab, 'state');
            app.LASCalibrationButton.ValueChangedFcn = createCallbackFcn(app, @LASCalibrationButtonValueChanged, true);
            app.LASCalibrationButton.Text = 'LAS Calibration';
            app.RunCounterEditFieldLabel = uilabel(app.WinchControlTab);
            app.RunCounterEditFieldLabel.Text = 'Run Counter ';
            app.RunCounterEditField = uieditfield(app.WinchControlTab, 'numeric');
            app.RunCounterEditField.Limits = [0 120];
            app.RunCounterEditField.ValueChangedFcn = createCallbackFcn(app, @RunCounterEditFieldValueChanged, true);
            app.ControlONtimesEditField_2 = uieditfield(app.WinchControlTab, 'numeric');
            app.ControlONtimesEditField_2.ValueChangedFcn = createCallbackFcn(app, @ControlONtimesEditField_2ValueChanged, true);
            app.Winch1StopDistancemEditFieldLabel = uilabel(app.WinchControlTab);
            app.Winch1StopDistancemEditFieldLabel.Text = 'Winch 1 Stop Distance(m)';
            app.Winch1StopDistancemEditField = uieditfield(app.WinchControlTab, 'numeric');
            app.Winch1StopDistancemEditField.ValueChangedFcn = createCallbackFcn(app, @Winch1StopDistancemEditFieldValueChanged, true);
            app.IMUYawCalibrationButton = uibutton(app.WinchControlTab, 'state');
            app.IMUYawCalibrationButton.ValueChangedFcn = createCallbackFcn(app, @IMUYawCalibrationButtonValueChanged, true);
            app.IMUYawCalibrationButton.Text = 'IMU Yaw Calibration';
            app.ControlONtimesEditField_3 = uieditfield(app.WinchControlTab, 'numeric');
            app.KiteControlTab = uitab(app.TabGroup);
            app.KiteControlTab.Title = 'Kite Control';
            app.PitchControlGainsLabel = uilabel(app.KiteControlTab);
            app.PitchControlGainsLabel.Text = 'Pitch Control Gains';
            app.RollControlGainsLabel = uilabel(app.KiteControlTab);
            app.RollControlGainsLabel.Text = 'Roll Control Gains';
            app.YawControlGainsLabel = uilabel(app.KiteControlTab);
            app.YawControlGainsLabel.Text = 'Yaw Control Gains';
            app.KpLabel = uilabel(app.KiteControlTab);
            app.KpLabel.Text = 'Kp';
            app.KdLabel = uilabel(app.KiteControlTab);
            app.KdLabel.Text = 'Kd';
            app.KiLabel = uilabel(app.KiteControlTab);
            app.KiLabel.Text = 'Ki';
            app.TauLabel = uilabel(app.KiteControlTab);
            app.TauLabel.Text = 'Tau';
            app.RollGainsKp = uieditfield(app.KiteControlTab, 'numeric');
            app.RollGainsKp.Limits = [-100 100];
            app.RollGainsKp.ValueChangedFcn = createCallbackFcn(app, @RollGainsKpValueChanged, true);
            app.RollGainsKp.Value = 1;
            app.RollGainsKd = uieditfield(app.KiteControlTab, 'numeric');
            app.RollGainsKd.ValueChangedFcn = createCallbackFcn(app, @RollGainsKdValueChanged, true);
            app.RollGainsKi = uieditfield(app.KiteControlTab, 'numeric');
            app.RollGainsKi.ValueChangedFcn = createCallbackFcn(app, @RollGainsKiValueChanged, true);
            app.RollGainsTau = uieditfield(app.KiteControlTab, 'numeric');
            app.RollGainsTau.ValueChangedFcn = createCallbackFcn(app, @RollGainsTauValueChanged, true);
            app.RollGainsTau.Value = 0.02;
            app.PitchGainsKp = uieditfield(app.KiteControlTab, 'numeric');
            app.PitchGainsKp.Limits = [-100 100];
            app.PitchGainsKp.ValueChangedFcn = createCallbackFcn(app, @PitchGainsKpValueChanged, true);
            app.PitchGainsKp.Value = 1;
            app.PitchGainsKd = uieditfield(app.KiteControlTab, 'numeric');
            app.PitchGainsKd.ValueChangedFcn = createCallbackFcn(app, @PitchGainsKdValueChanged, true);
            app.PitchGainsKi = uieditfield(app.KiteControlTab, 'numeric');
            app.PitchGainsKi.ValueChangedFcn = createCallbackFcn(app, @PitchGainsKiValueChanged, true);
            app.PitchGainsTau = uieditfield(app.KiteControlTab, 'numeric');
            app.PitchGainsTau.ValueChangedFcn = createCallbackFcn(app, @PitchGainsTauValueChanged, true);
            app.PitchGainsTau.Value = 0.02;
            app.YawGainsKp = uieditfield(app.KiteControlTab, 'numeric');
            app.YawGainsKp.ValueChangedFcn = createCallbackFcn(app, @YawGainsKpValueChanged, true);
            app.YawGainsKp.Value = 1;
            app.YawGainsKd = uieditfield(app.KiteControlTab, 'numeric');
            app.YawGainsKd.ValueChangedFcn = createCallbackFcn(app, @YawGainsKdValueChanged, true);
            app.YawGainsKi = uieditfield(app.KiteControlTab, 'numeric');
            app.YawGainsKi.ValueChangedFcn = createCallbackFcn(app, @YawGainsKiValueChanged, true);
            app.YawGainsTau = uieditfield(app.KiteControlTab, 'numeric');
            app.YawGainsTau.ValueChangedFcn = createCallbackFcn(app, @YawGainsTauValueChanged, true);
            app.YawGainsTau.Value = 0.02;
            app.ElevatorDeflectionClosedLoopdegEditFieldLabel = uilabel(app.KiteControlTab);
            app.ElevatorDeflectionClosedLoopdegEditFieldLabel.Text = 'Elevator Deflection Closed Loop (deg)';
            app.ElevatorDeflectionClosedLoopdegEditField = uieditfield(app.KiteControlTab, 'numeric');
            app.ElevatorDeflectionClosedLoopdegEditField.ValueChangedFcn = createCallbackFcn(app, @ElevatorDeflectionClosedLoopdegEditFieldValueChanged, true);
            app.RollControlSwitch = uiswitch(app.KiteControlTab, 'slider');
            app.RollControlSwitch.Items = {'Open', 'Closed'};
            app.RollControlSwitch.ValueChangedFcn = createCallbackFcn(app, @RollControlSwitchValueChanged, true);
            app.RollControlSwitch.Value = 'Open';
            app.PitchControlSwitch = uiswitch(app.KiteControlTab, 'slider');
            app.PitchControlSwitch.Items = {'Open', 'Closed'};
            app.PitchControlSwitch.ValueChangedFcn = createCallbackFcn(app, @PitchControlSwitchValueChanged, true);
            app.PitchControlSwitch.Value = 'Open';
            app.YawControlSwitch = uiswitch(app.KiteControlTab, 'slider');
            app.YawControlSwitch.Items = {'Open', 'Closed'};
            app.YawControlSwitch.ValueChangedFcn = createCallbackFcn(app, @YawControlSwitchValueChanged, true);
            app.YawControlSwitch.Value = 'Open';
            app.AileronDeflectiondegEditFieldLabel = uilabel(app.KiteControlTab);
            app.AileronDeflectiondegEditFieldLabel.Text = 'Aileron Deflection (deg)';
            app.AileronDeflectiondegEditField = uieditfield(app.KiteControlTab, 'numeric');
            app.AileronDeflectiondegEditField.ValueChangedFcn = createCallbackFcn(app, @AileronDeflectiondegEditFieldValueChanged, true);
            app.RudderDeflectiondegEditFieldLabel = uilabel(app.KiteControlTab);
            app.RudderDeflectiondegEditFieldLabel.Text = 'Rudder Deflection (deg)';
            app.RudderDeflectiondegEditField = uieditfield(app.KiteControlTab, 'numeric');
            app.RudderDeflectiondegEditField.ValueChangedFcn = createCallbackFcn(app, @RudderDeflectiondegEditFieldValueChanged, true);
            app.RollSetPointLabel = uilabel(app.KiteControlTab);
            app.RollSetPointLabel.Text = 'Roll Set Point';
            app.SPEditFieldLabel = uilabel(app.KiteControlTab);
            app.SPEditFieldLabel.Text = 'SP';
            app.RollConstantSP = uieditfield(app.KiteControlTab, 'text');
            app.RollConstantSP.ValueChangedFcn = createCallbackFcn(app, @RollConstantSPValueChanged, true);
            app.RollConstantSP.Value = '0';
            app.AmpEditFieldLabel = uilabel(app.KiteControlTab);
            app.AmpEditFieldLabel.Text = 'Amp';
            app.RollSineAmp = uieditfield(app.KiteControlTab, 'text');
            app.RollSineAmp.ValueChangedFcn = createCallbackFcn(app, @RollSineAmpValueChanged, true);
            app.RollSineAmp.Value = '15';
            app.PeriodEditFieldLabel = uilabel(app.KiteControlTab);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.RollSinePeriod = uieditfield(app.KiteControlTab, 'text');
            app.RollSinePeriod.ValueChangedFcn = createCallbackFcn(app, @RollSinePeriodValueChanged, true);
            app.RollSinePeriod.Value = '5';
            app.AmpEditField_2Label = uilabel(app.KiteControlTab);
            app.AmpEditField_2Label.Text = 'Amp';
            app.RollSquareAmp = uieditfield(app.KiteControlTab, 'text');
            app.RollSquareAmp.ValueChangedFcn = createCallbackFcn(app, @RollSquareAmpValueChanged, true);
            app.RollSquareAmp.Value = '15';
            app.PeriodEditField_2Label = uilabel(app.KiteControlTab);
            app.PeriodEditField_2Label.Text = 'Period';
            app.RollSquarePeriod = uieditfield(app.KiteControlTab, 'text');
            app.RollSquarePeriod.ValueChangedFcn = createCallbackFcn(app, @RollSquarePeriodValueChanged, true);
            app.RollSquarePeriod.Value = '5';
            app.PitchSetPointLabel_2 = uilabel(app.KiteControlTab);
            app.PitchSetPointLabel_2.Text = 'Pitch Set Point';
            app.SPEditField_2Label = uilabel(app.KiteControlTab);
            app.SPEditField_2Label.Text = 'SP';
            app.PitchConstantSP = uieditfield(app.KiteControlTab, 'text');
            app.PitchConstantSP.ValueChangedFcn = createCallbackFcn(app, @PitchConstantSPValueChanged, true);
            app.PitchConstantSP.Value = '0';
            app.AmpEditField_3Label = uilabel(app.KiteControlTab);
            app.AmpEditField_3Label.Text = 'Amp';
            app.PitchSineAmp = uieditfield(app.KiteControlTab, 'text');
            app.PitchSineAmp.ValueChangedFcn = createCallbackFcn(app, @PitchSineAmpValueChanged, true);
            app.PitchSineAmp.Value = '0';
            app.PeriodEditField_3Label = uilabel(app.KiteControlTab);
            app.PeriodEditField_3Label.Text = 'Period';
            app.PitchSinePeriod = uieditfield(app.KiteControlTab, 'text');
            app.PitchSinePeriod.ValueChangedFcn = createCallbackFcn(app, @PitchSinePeriodValueChanged, true);
            app.PitchSinePeriod.Value = '0';
            app.AmpEditField_4Label = uilabel(app.KiteControlTab);
            app.AmpEditField_4Label.Text = 'Amp';
            app.PitchSquareAmp = uieditfield(app.KiteControlTab, 'text');
            app.PitchSquareAmp.ValueChangedFcn = createCallbackFcn(app, @PitchSquareAmpValueChanged, true);
            app.PitchSquareAmp.Value = '0';
            app.PeriodEditField_4Label = uilabel(app.KiteControlTab);
            app.PeriodEditField_4Label.Text = 'Period';
            app.PitchSquarePeriod = uieditfield(app.KiteControlTab, 'text');
            app.PitchSquarePeriod.ValueChangedFcn = createCallbackFcn(app, @PitchSquarePeriodValueChanged, true);
            app.PitchSquarePeriod.Value = '0';
            app.YawSetPointLabel = uilabel(app.KiteControlTab);
            app.YawSetPointLabel.Text = 'Yaw Set Point';
            app.SPEditField_3Label = uilabel(app.KiteControlTab);
            app.SPEditField_3Label.Text = 'SP';
            app.YawConstantSP = uieditfield(app.KiteControlTab, 'text');
            app.YawConstantSP.ValueChangedFcn = createCallbackFcn(app, @YawConstantSPValueChanged, true);
            app.YawConstantSP.Value = '0';
            app.AmpEditField_5Label = uilabel(app.KiteControlTab);
            app.AmpEditField_5Label.Text = 'Amp';
            app.YawSineAmp = uieditfield(app.KiteControlTab, 'text');
            app.YawSineAmp.ValueChangedFcn = createCallbackFcn(app, @YawSineAmpValueChanged, true);
            app.YawSineAmp.Value = '0';
            app.PeriodEditField_5Label = uilabel(app.KiteControlTab);
            app.PeriodEditField_5Label.Text = 'Period';
            app.YawSinePeriod = uieditfield(app.KiteControlTab, 'text');
            app.YawSinePeriod.ValueChangedFcn = createCallbackFcn(app, @YawSinePeriodValueChanged, true);
            app.YawSinePeriod.Value = '0';
            app.AmpEditField_6Label = uilabel(app.KiteControlTab);
            app.AmpEditField_6Label.Text = 'Amp';
            app.YawSquareAmp = uieditfield(app.KiteControlTab, 'text');
            app.YawSquareAmp.ValueChangedFcn = createCallbackFcn(app, @YawSquareAmpValueChanged, true);
            app.YawSquareAmp.Value = '0';
            app.PeriodEditField_6Label = uilabel(app.KiteControlTab);
            app.PeriodEditField_6Label.Text = 'Period';
            app.YawSquarePeriod = uieditfield(app.KiteControlTab, 'text');
            app.YawSquarePeriod.ValueChangedFcn = createCallbackFcn(app, @YawSquarePeriodValueChanged, true);
            app.YawSquarePeriod.Value = '0';
            app.ControllerSwitchLabel = uilabel(app.KiteControlTab);
            app.ControllerSwitchLabel.Text = 'Controller';
            app.ControllerSwitch = uiswitch(app.KiteControlTab, 'toggle');
            app.ControllerSwitch.ValueChangedFcn = createCallbackFcn(app, @ControllerSwitchValueChanged, true);
            app.IntegralControlResetButton = uibutton(app.KiteControlTab, 'push');
            app.IntegralControlResetButton.ButtonPushedFcn = createCallbackFcn(app, @IntegralControlResetButtonPushed, true);
            app.IntegralControlResetButton.Text = 'Integral Control Reset';
            app.ElevatorDeflectionOpenLoopdegLabel = uilabel(app.KiteControlTab);
            app.ElevatorDeflectionOpenLoopdegLabel.Text = 'Elevator Deflection Open Loop (deg)';
            app.ElevatorDeflectionOpenLoopdegEditField = uieditfield(app.KiteControlTab, 'numeric');
            app.ElevatorDeflectionOpenLoopdegEditField.ValueChangedFcn = createCallbackFcn(app, @ElevatorDeflectionOpenLoopdegEditFieldValueChanged, true);
            app.RollSetPointButtons = uibuttongroup(app.KiteControlTab);
            app.RollSetPointButtons.SelectionChangedFcn = createCallbackFcn(app, @RollSetPointButtonsSelectionChanged, true);
            app.RollSPConstantButton = uiradiobutton(app.RollSetPointButtons);
            app.RollSPConstantButton.Text = 'Constant';
            app.RollSPConstantButton.Value = true;
            app.RollSPSineButton = uiradiobutton(app.RollSetPointButtons);
            app.RollSPSineButton.Text = 'Sine';
            app.RollSPSquareButton = uiradiobutton(app.RollSetPointButtons);
            app.RollSPSquareButton.Text = 'Square';
            app.RollSPTSButton = uiradiobutton(app.RollSetPointButtons);
            app.RollSPTSButton.Text = 'TimeSeries';
            app.PitchSetPointButtons = uibuttongroup(app.KiteControlTab);
            app.PitchSetPointButtons.SelectionChangedFcn = createCallbackFcn(app, @PitchSetPointButtonsSelectionChanged, true);
            app.PitchSPConstantButton = uiradiobutton(app.PitchSetPointButtons);
            app.PitchSPConstantButton.Text = 'Constant';
            app.PitchSPConstantButton.Value = true;
            app.PitchSPSineButton = uiradiobutton(app.PitchSetPointButtons);
            app.PitchSPSineButton.Text = 'Sine';
            app.PitchSPSquareButton = uiradiobutton(app.PitchSetPointButtons);
            app.PitchSPSquareButton.Text = 'Square';
            app.PitchSPTSButton = uiradiobutton(app.PitchSetPointButtons);
            app.PitchSPTSButton.Text = 'TimeSeries';
            app.YawSetPointButtons = uibuttongroup(app.KiteControlTab);
            app.YawSetPointButtons.SelectionChangedFcn = createCallbackFcn(app, @YawSetPointButtonsSelectionChanged, true);
            app.YawSPConstantButton = uiradiobutton(app.YawSetPointButtons);
            app.YawSPConstantButton.Text = 'Constant';
            app.YawSPConstantButton.Value = true;
            app.YawSPSineButton = uiradiobutton(app.YawSetPointButtons);
            app.YawSPSineButton.Text = 'Sine';
            app.YawSPSquareButton = uiradiobutton(app.YawSetPointButtons);
            app.YawSPSquareButton.Text = 'Square';
            app.YawSPTSButton = uiradiobutton(app.YawSetPointButtons);
            app.YawSPTSButton.Text = 'TimeSeries';
            app.ControllerTriggerButtonGroup = uibuttongroup(app.KiteControlTab);
            app.ControllerTriggerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ControllerTriggerButtonGroupSelectionChanged, true);
            app.ControllerTriggerButtonGroup.Title = 'Controller Trigger';
            app.ManualButton_2 = uiradiobutton(app.ControllerTriggerButtonGroup);
            app.ManualButton_2.Text = 'Manual';
            app.ManualButton_2.Value = true;
            app.LASSpeedButton = uiradiobutton(app.ControllerTriggerButtonGroup);
            app.LASSpeedButton.Text = 'LAS Speed';
            app.EncPositionButton = uiradiobutton(app.ControllerTriggerButtonGroup);
            app.EncPositionButton.Text = 'Enc Position';
            app.TimeButton = uiradiobutton(app.ControllerTriggerButtonGroup);
            app.TimeButton.Text = 'Time';
            app.ControlTriggTimeInput = uieditfield(app.KiteControlTab, 'text');
            app.ControlTriggTimeInput.ValueChangedFcn = createCallbackFcn(app, @ControlTriggTimeInputValueChanged, true);
            app.ControlTriggTimeInput.Value = '10';
            app.ControlTriggLASSpeedInput = uieditfield(app.KiteControlTab, 'text');
            app.ControlTriggLASSpeedInput.ValueChangedFcn = createCallbackFcn(app, @ControlTriggLASSpeedInputValueChanged, true);
            app.ControlTriggLASSpeedInput.Value = '1';
            app.ControlTriggEncPosInput = uieditfield(app.KiteControlTab, 'text');
            app.ControlTriggEncPosInput.ValueChangedFcn = createCallbackFcn(app, @ControlTriggEncPosInputValueChanged, true);
            app.ControlTriggEncPosInput.Value = '3';
            app.PhaseLagLabel = uilabel(app.KiteControlTab);
            app.PhaseLagLabel.Text = 'PhaseLag';
            app.RollSineLag = uieditfield(app.KiteControlTab, 'text');
            app.RollSineLag.ValueChangedFcn = createCallbackFcn(app, @RollSineLagValueChanged, true);
            app.RollSineLag.Value = '2';
            app.PhaseLagLabel_2 = uilabel(app.KiteControlTab);
            app.PhaseLagLabel_2.Text = 'PhaseLag';
            app.RollSquareLag = uieditfield(app.KiteControlTab, 'text');
            app.RollSquareLag.ValueChangedFcn = createCallbackFcn(app, @RollSquareLagValueChanged, true);
            app.RollSquareLag.Value = '2';
            app.PhaseLagLabel_3 = uilabel(app.KiteControlTab);
            app.PhaseLagLabel_3.Text = 'PhaseLag';
            app.PitchSineLag = uieditfield(app.KiteControlTab, 'text');
            app.PitchSineLag.ValueChangedFcn = createCallbackFcn(app, @PitchSineLagValueChanged, true);
            app.PitchSineLag.Value = '0';
            app.PhaseLagLabel_4 = uilabel(app.KiteControlTab);
            app.PhaseLagLabel_4.Text = 'PhaseLag';
            app.PitchSquareLag = uieditfield(app.KiteControlTab, 'text');
            app.PitchSquareLag.ValueChangedFcn = createCallbackFcn(app, @PitchSquareLagValueChanged, true);
            app.PitchSquareLag.Value = '0';
            app.PhaseLagLabel_5 = uilabel(app.KiteControlTab);
            app.PhaseLagLabel_5.Text = 'PhaseLag';
            app.YawSineLag = uieditfield(app.KiteControlTab, 'text');
            app.YawSineLag.ValueChangedFcn = createCallbackFcn(app, @YawSineLagValueChanged, true);
            app.YawSineLag.Value = '0';
            app.PhaseLagLabel_6 = uilabel(app.KiteControlTab);
            app.PhaseLagLabel_6.Text = 'PhaseLag';
            app.YawSquareLag = uieditfield(app.KiteControlTab, 'text');
            app.YawSquareLag.ValueChangedFcn = createCallbackFcn(app, @YawSquareLagValueChanged, true);
            app.YawSquareLag.Value = '0';
            app.CloseToOpenTriggersLabel = uilabel(app.KiteControlTab);
            app.CloseToOpenTriggersLabel.Text = 'Close-To-Open Trigger (s)';
            app.ZeroAmpTrigg = uieditfield(app.KiteControlTab, 'numeric');
            app.ZeroAmpTrigg.ValueChangedFcn = createCallbackFcn(app, @ZeroAmpTriggValueChanged, true);
            app.PreFlightRollControlSwitchLabel = uilabel(app.KiteControlTab);
            app.PreFlightRollControlSwitchLabel.Text = 'Pre-Flight Roll Control';
            app.PreFlightRollControlSwitch = uiswitch(app.KiteControlTab, 'slider');
            app.PreFlightRollControlSwitch.Items = {'Open', 'Closed'};
            app.PreFlightRollControlSwitch.ValueChangedFcn = createCallbackFcn(app, @PreFlightRollControlSwitchValueChanged, true);
            app.PreFlightRollControlSwitch.Value = 'Open';
            app.HierarchicalControllerTab = uitab(app.TabGroup);
            app.HierarchicalControllerTab.Title = 'Hierarchical Controller';
            app.PerpendicularErrorControllerKpEditFieldLabel = uilabel(app.HierarchicalControllerTab);
            app.PerpendicularErrorControllerKpEditFieldLabel.Text = 'Perpendicular Error Controller (Kp)';
            app.PerpendicularErrorControllerKpEditField = uieditfield(app.HierarchicalControllerTab, 'numeric');
            app.PerpendicularErrorControllerKpEditField.ValueChangedFcn = createCallbackFcn(app, @PerpendicularErrorControllerKpEditFieldValueChanged, true);
            app.VelocityAngleControllerKpEditFieldLabel = uilabel(app.HierarchicalControllerTab);
            app.VelocityAngleControllerKpEditFieldLabel.Text = 'Velocity Angle Controller (Kp)';
            app.VelocityAngleControllerKpEditField = uieditfield(app.HierarchicalControllerTab, 'numeric');
            app.VelocityAngleControllerKpEditField.ValueChangedFcn = createCallbackFcn(app, @VelocityAngleControllerKpEditFieldValueChanged, true);
            app.SideSlipAngleControllerKpEditFieldLabel = uilabel(app.HierarchicalControllerTab);
            app.SideSlipAngleControllerKpEditFieldLabel.Text = 'Side Slip Angle Controller (Kp)';
            app.SideSlipAngleControllerKpEditField = uieditfield(app.HierarchicalControllerTab, 'numeric');
            app.SideSlipAngleControllerKpEditField.ValueChangedFcn = createCallbackFcn(app, @SideSlipAngleControllerKpEditFieldValueChanged, true);
            app.TangentRollAngleControllerLabel = uilabel(app.HierarchicalControllerTab);
            app.TangentRollAngleControllerLabel.Text = 'Tangent Roll Angle Controller';
            app.TanRollKpLabel = uilabel(app.HierarchicalControllerTab);
            app.TanRollKpLabel.Text = 'Kp';
            app.TanRollKdLabel = uilabel(app.HierarchicalControllerTab);
            app.TanRollKdLabel.Text = 'Kd';
            app.TanRollTauLabel = uilabel(app.HierarchicalControllerTab);
            app.TanRollTauLabel.Text = 'Tau';
            app.TanRollKpValue = uieditfield(app.HierarchicalControllerTab, 'numeric');
            app.TanRollKpValue.Limits = [-100 100];
            app.TanRollKpValue.ValueChangedFcn = createCallbackFcn(app, @TanRollKpValueValueChanged, true);
            app.TanRollKpValue.Value = 1;
            app.TanRollKdValue = uieditfield(app.HierarchicalControllerTab, 'numeric');
            app.TanRollKdValue.ValueChangedFcn = createCallbackFcn(app, @TanRollKdValueValueChanged, true);
            app.TanRollTauValue = uieditfield(app.HierarchicalControllerTab, 'numeric');
            app.TanRollTauValue.ValueChangedFcn = createCallbackFcn(app, @TanRollTauValueValueChanged, true);
            app.TanRollTauValue.Value = 0.02;
            app.BasisParamatersLabel = uilabel(app.HierarchicalControllerTab);
            app.BasisParamatersLabel.Text = 'Basis Paramaters';
            app.PathWidthdegEditFieldLabel = uilabel(app.HierarchicalControllerTab);
            app.PathWidthdegEditFieldLabel.Text = 'Path Width (deg)';
            app.PathWidthdegEditField = uieditfield(app.HierarchicalControllerTab, 'numeric');
            app.PathWidthdegEditField.ValueChangedFcn = createCallbackFcn(app, @PathWidthdegEditFieldValueChanged, true);
            app.PathHeightdegEditFieldLabel = uilabel(app.HierarchicalControllerTab);
            app.PathHeightdegEditFieldLabel.Text = 'Path Height (deg)';
            app.PathHeightdegEditField = uieditfield(app.HierarchicalControllerTab, 'numeric');
            app.PathHeightdegEditField.ValueChangedFcn = createCallbackFcn(app, @PathHeightdegEditFieldValueChanged, true);
            app.CenterElevationAngledegEditFieldLabel = uilabel(app.HierarchicalControllerTab);
            app.CenterElevationAngledegEditFieldLabel.Text = 'Center Elevation Angle (deg)';
            app.CenterElevationAngledegEditField = uieditfield(app.HierarchicalControllerTab, 'numeric');
            app.CenterElevationAngledegEditField.ValueChangedFcn = createCallbackFcn(app, @CenterElevationAngledegEditFieldValueChanged, true);
            app.CenterAzimuthAngledegEditFieldLabel = uilabel(app.HierarchicalControllerTab);
            app.CenterAzimuthAngledegEditFieldLabel.Text = 'Center Azimuth Angle (deg)';
            app.CenterAzimuthAngledegEditField = uieditfield(app.HierarchicalControllerTab, 'numeric');
            app.CenterAzimuthAngledegEditField.ValueChangedFcn = createCallbackFcn(app, @CenterAzimuthAngledegEditFieldValueChanged, true);
            app.TetherLengthmEditFieldLabel = uilabel(app.HierarchicalControllerTab);
            app.TetherLengthmEditFieldLabel.Text = 'Tether Length (m)';
            app.TetherLengthmEditField = uieditfield(app.HierarchicalControllerTab, 'numeric');
            app.TetherLengthmEditField.ValueChangedFcn = createCallbackFcn(app, @TetherLengthmEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appforPool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end